classdef dog_action_recognition < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        TabGroup        matlab.ui.container.TabGroup
        Tab             matlab.ui.container.Tab
        GridLayout3     matlab.ui.container.GridLayout
        Panel           matlab.ui.container.Panel
        GridLayout2     matlab.ui.container.GridLayout
        GridLayout      matlab.ui.container.GridLayout
        actdog          matlab.ui.control.Button
        acttype         matlab.ui.control.Button
        Image_act       matlab.ui.control.Image
        connectButton   matlab.ui.control.Button
        stopButton      matlab.ui.control.Button
        startButton     matlab.ui.control.Button
        Image_position  matlab.ui.control.Image
        catch_vot       matlab.ui.control.UIAxes
        ori_vot         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        % Description
        ifUsepy=1;
        byIndex;
        real_vot_1=[];  real_vot_2=[];  real_vot_3=[];  real_vot_4=[];real_vot_5=[];real_vot_6=[];
        ori_vot_1 = []; ori_vot_2 = []; ori_vot_3 = []; ori_vot_4 = [];ori_vot_5 = [];ori_vot_6 = [];
        V1_all = [];V2_all=[];V3_all=[];V4_all=[];V5_all=[];V6_all=[];xdata_save=[];
        V1_acttest;V2_acttest;V3_acttest;V4_acttest;V5_acttest;V6_acttest;
        V1;V2;V3;V4;V5;V6;data_total;
        vot_integ_1 = 0;
        vot_integ_2 = 0;
        vot_integ_3 = 0;
        vot_integ_4 = 0;
        vot_integ_5 = 0;
        vot_integ_6 = 0;
        timer_id;
        xData = [];
        data_f = 1000;
        bufflen=10000;
        data_len=0;
        data_len_total=0;
        mean_num = 2000;
        zf1; zf2; zf3; zf4;zf5;zf6;
        zf11; zf21; zf31; zf41;zf51;zf61;
        zf12; zf22; zf32; zf42;zf52;zf62;
        Mat1 = [];
        Mat2 = [];
        w1;w2;mu;
        countOfCsv = 1;
        ydata;
        last_posi = 0;
        posi=0;
        csvlen=100;
        word_x;word_y;
        temp_x;temp_y;
        OutYall=[];
        noiseall=[];
        mean_i = 0*ones(6,1);
        lastpeak = 0;
        zStart = -1;
        zEnd = -1;
        fitresult;fitresult2;
        h_Ori; h_Ori2;h_Ori3; h_Ori4;h_Ori5;
        h_Integ;
        h_Integ1;h_Integ2;
        h_R1;h_R2;
        integ_eff = 0;
        thr = 0.06;
        tcmode = 1;
        integ_all;
        startsave=0;
        endsave = 0;
        thr_net = 0.1;
        YPred=[];
        timernum = 0;
        ifsave= 1;
        crest_index;
        showtime=0;
        showtime_1=0;
        showtime_2 = 0;
        net;
        nameData = ["knock","pat","pet","press","push","slap","tickle","touch"];
        dog_act;
        dog_ble;
        ifact=0;
        ifheavy=1;
        temper = 0;
        iftouch = 0;

        Ratio1;Ratio2;
    end
    
    methods (Access = public)
        function adc_timer(app, ~, ~)

            %get row data
            try
                readAd7606(app);
            catch MException
                disp(MException)
            end
            
            if app.data_len>0

                try
                    
                    xdata = app.xData(app.bufflen-app.data_len+1:app.bufflen);
                    %save data
                    if app.startsave && ~app.endsave
                        app.V1_all = [app.V1_all,app.V1];
                        app.V2_all = [app.V2_all,app.V2];
                        app.V3_all = [app.V3_all,app.V3];
                        app.V4_all = [app.V4_all,app.V4];
                        app.V5_all = [app.V5_all,app.V5];
                        app.V6_all = [app.V6_all,app.V6];
                        app.xdata_save = [app.xdata_save,xdata];
                    end


                    %output row data
                    addpoints(app.h_Ori,xdata,app.V1);
                    addpoints(app.h_Ori2,xdata,app.V2+0.2);
                    addpoints(app.h_Ori3,xdata,app.V3+0.4);
                    addpoints(app.h_Ori4,xdata,app.V4+0.6);
                    addpoints(app.h_Ori5,xdata,(app.V5-app.V6)*0.2+0.8);
                    %sum data
                    app.data_total = app.V1+app.V2+app.V3+app.V4;
                    if min(app.data_total)<-0.05
                        reset_pic(app)
                    end
                    %find peaks
                    [peaks_data_1,peaks_index_1] = findpeaks(app.V1);
                    [peaks_data_2,peaks_index_2] = findpeaks(app.V2);
                    [peaks_data_3,peaks_index_3] = findpeaks(app.V3);
                    [peaks_data_4,peaks_index_4] = findpeaks(app.V4);
                    
                    [peaks_data_total,peaks_index_total] = findpeaks(app.data_total);
                    %
                    if ~isempty(peaks_data_total)
                        if max(peaks_data_total)>0.4
                            app.ifheavy = 1;
                        else
                            app.ifheavy = 2;
                        end
                        
                    end
                    %get valid peaks
                    peaks_right = find(peaks_data_total>app.thr);
                    peaks_index_right = peaks_index_total;
                    peaks_1 = right_peak(app,peaks_right,peaks_index_right,peaks_index_1,peaks_data_1,0);
                    peaks_2 = right_peak(app,peaks_right,peaks_index_right,peaks_index_2,peaks_data_2,0);
                    peaks_3 = right_peak(app,peaks_right,peaks_index_right,peaks_index_3,peaks_data_3,0);
                    peaks_4 = right_peak(app,peaks_right,peaks_index_right,peaks_index_4,peaks_data_4,0);
                    
                    DVM1 = peaks_data_1(peaks_1);
                    DVM2 = peaks_data_2(peaks_2);
                    DVM3 = peaks_data_3(peaks_3);
                    DVM4 = peaks_data_4(peaks_4);
                    

                    app.Ratio1 = zeros(1,length(DVM1));
                    app.Ratio2 = zeros(1,length(DVM1));
                    %get ratio
                    for i = 1:length(DVM1)    
                        app.Ratio1(i) = (DVM1(i)+DVM2(i))./(DVM1(i)+DVM2(i)+DVM3(i)+DVM4(i));
                        app.Ratio2(i) = (DVM1(i)+DVM4(i))./(DVM1(i)+DVM2(i)+DVM3(i)+DVM4(i));
                    end
                    %remove 0
                    R_1 = find(app.Ratio1~=0);
                    app.Ratio1 = app.Ratio1(R_1);
                    app.Ratio2 = app.Ratio2(R_1);
                    if ~isempty(app.Ratio1) && ~isempty(app.Ratio2)
                        [app.word_x,app.word_y] = locword(app,app.Ratio1(end),app.Ratio2(end));
                        disp([app.Ratio1;app.Ratio2])
                        disp([app.word_x;app.word_y])
                        plot_position(app);
                    end
                    
                    
                    app.V1_acttest = [app.V1_acttest,app.V1];
                    app.V2_acttest = [app.V2_acttest,app.V2];
                    app.V3_acttest = [app.V3_acttest,app.V3];
                    app.V4_acttest = [app.V4_acttest,app.V4];
                    single_length = length(app.V1);
                    
                    app.timernum = app.timernum+1;
                    app.showtime = app.showtime+1;
                    app.showtime_2 = app.showtime_2 +1;
                    app.showtime_1 = app.showtime_1+1;
                    %act end
                    if app.showtime>40 && app.ifact ==1
                        app.acttype.Text = "None";
                        app.actdog.Text = "None";
                        app.Image_act.ImageSource = 'picture/ori_act.png';
                        app.ifact = 0;
                        app.ifsave = 1;
                    end
                    %no touch
                    if app.showtime_2>40
                        app.iftouch=0;
                    end

                    if app.showtime_1>40 && app.temper == 1
                        disp('reset')
                        app.acttype.Text = "None";
                        app.actdog.Text = "None";
                        app.Image_act.ImageSource = 'picture/ori_act.png';
                        app.temper = 0;
                    end


                    peaks_find = find(peaks_data_total>app.thr_net);
                    peaks_index_find = peaks_index_total(peaks_find);
                    peaks_data_find = peaks_data_total(peaks_find);
                    
            
                    %find valid wave
                    if length(peaks_data_find)>=1 && app.ifsave && app.temper==0
                        
                        [~,max_index] = max(peaks_data_find);
                        app.showtime = 0;
                        app.showtime_2 = 0;
                        app.iftouch = 1;
                        app.crest_index = peaks_index_find(max_index)+length(app.V1_acttest)-single_length;
                        
                        app.ifsave = 0;
                        app.acttype.Text = "Identifying";
                        app.actdog.Text = "Waiting";
                    end
                    num1 = 300;
                    num2 = 900;
                    %Recognition action type
                    if length(app.V1_acttest)> app.crest_index+num2 && app.ifsave == 0 && app.ifact ~=1 && app.temper==0
                        app.ifact = 1;
                        
                        app.showtime = 0;
                        app.showtime_2 = 0;
                        app.iftouch = 1;
                        right_sco = app.crest_index-num1:app.crest_index+num2;
                        temp_data = app.V1_acttest(right_sco)+app.V2_acttest(right_sco)+app.V3_acttest(right_sco)+app.V4_acttest(right_sco);
                        
                        clearpoints(app.h_R1)
                        addpoints(app.h_R1,1:length(temp_data),temp_data);
 
                        vaild_data = temp_data;
                        
                        vaild_cell = ones(size(vaild_data,1),1);   
                        TestData = mat2cell(vaild_data,vaild_cell);  
                        
                        app.YPred = classify(app.net,TestData);
                        app.YPred=int32(app.YPred);
                        

                        app.acttype.Text = app.nameData(app.YPred);
                        
                     
%                         ["knock","pat","pet","press","push","slap","tickle","touch"]
                        %feedback based on the type of action
                        if app.YPred == 7  
                            app.Image_act.ImageSource = 'picture/tickle.png';
                            app.actdog.Text = "Scratch";
                            write(app.dog_act,"kscrh");
                            
                        elseif app.YPred == 3
                            app.Image_act.ImageSource = 'picture/pet.png';
                            app.actdog.Text = "Sit";
                            write(app.dog_act,"ksit");
                           
                        elseif app.YPred == 6
                            app.Image_act.ImageSource = 'picture/slap.png';
                            app.actdog.Text = "Shake";
                            write(app.dog_act,"kshk");
                           
                        elseif app.YPred == 8 && (app.word_x == 1 || app.word_x == 2)
                            app.Image_act.ImageSource = 'picture/touch.png';
                            app.actdog.Text = "Come here";
                            write(app.dog_act,"kcmhL");
                            pause(3)
                        elseif app.YPred == 8 && app.word_x == 3
                            app.Image_act.ImageSource = 'picture/touch.png';
                            app.actdog.Text = "Come here";
                            write(app.dog_act,"kcmhR");
                           
                        elseif app.YPred == 5 && (app.word_x == 1 || app.word_x == 2)
                            app.Image_act.ImageSource = 'picture/push.png';
                            app.actdog.Text = "Wave head";
                            write(app.dog_act,'kvtL')
                            pause(2)
                            write(app.dog_act,'kwh')
                        elseif app.YPred == 5 && app.word_x == 3
                            app.Image_act.ImageSource = 'picture/push.png';
                            app.actdog.Text = "Wave head";
                            write(app.dog_act,'kvtR')
                            
                            write(app.dog_act,'kwh')
                        elseif app.YPred == 4
                            app.Image_act.ImageSource = 'picture/press.png';
                            app.actdog.Text = "Rest";
                            write(app.dog_act,"krest");
                           
                        elseif app.YPred == 1
                            app.Image_act.ImageSource = 'picture/knock.png';
                            app.actdog.Text = "Dig";
                            write(app.dog_act,"kdg");
                         
                        elseif app.YPred == 2
                            app.Image_act.ImageSource = 'picture/pat.png';
                            app.actdog.Text = "Sniff";
                            write(app.dog_act,"ksnf");
                           
                        end
                        
                    end
                    %temperature recognization
                    if max(abs(app.V1_acttest(end-5000:end)))<0.05 && max(abs(app.V2_acttest(end-5000:end)))<0.05&& max(abs(app.V3_acttest(end-5000:end)))<0.05&& max(abs(app.V4_acttest(end-5000:end)))<0.05&&app.temper == 0&&app.iftouch==0
                        if mean(app.V5-app.V6)<-0.1
                            disp('cold')
                            app.temper = 1;
                            app.showtime_1 = 0; 
                            app.acttype.Text = "Cold!";
                            app.actdog.Text = "Wave head";
                            app.Image_act.ImageSource = 'picture/ice.png';
                            write(app.dog_act,"kwh");
                            pause(2)
                                   
                        elseif mean(app.V5-app.V6)>0.5
                            disp('hot')
                            app.temper = 1;
                            app.showtime_1 = 0; 
                            app.acttype.Text = "Warm~";
                            app.actdog.Text = "Stretch";
                            app.Image_act.ImageSource = 'picture/hair_drier.png';
                            write(app.dog_act,"kstr");
                            write(app.dog_act,"krest");
                            pause(1)
                            
                        end
                    end
                   
                    
                catch MException
                     disp(MException);
                     disp('峰值检测错误')
                end
   
            end


        end
    end
    
    methods (Access = private)
        function openAd7606(app)
            py.importlib.import_module('ad7606_read');
            obj = py.importlib.import_module('ad7606_read');
            py.importlib.reload(obj);
            P = py.sys.path; 
            if count(P,'./ad7606_read.py') == 0
               insert(P,int32(0),'./ad7606_read.py');
            end
            py.importlib.import_module('ad7606_read');
            delete(timerfind);
            app.byIndex = py.ad7606_read.openadc(int32(1000000/app.data_f));
            py.ad7606_read.adc_startread(app.byIndex);
        end

        function readAd7606(app)
            real_vot = double(py.ad7606_read.readadc_data(app.byIndex))';       
            data_len_r = length(real_vot);
            app.data_len = data_len_r / 8;
            if app.data_len > 0
                temp_vot_1 = zeros(app.data_len,1);
                temp_vot_2 = zeros(app.data_len,1);
                temp_vot_3 = zeros(app.data_len,1);
                temp_vot_4 = zeros(app.data_len,1);
                temp_vot_5 = zeros(app.data_len,1);
                temp_vot_6 = zeros(app.data_len,1);
                for i = 1:app.data_len
                    temp_vot_1(i) = real_vot(i*8-7);
                    temp_vot_2(i) = real_vot(i*8-6);
                    temp_vot_3(i) = real_vot(i*8-5);
                    temp_vot_4(i) = real_vot(i*8-4);
                    temp_vot_5(i) = real_vot(i*8-3);
                    temp_vot_6(i) = real_vot(i*8-2);
                end


                [~,b]=lp_20_50(app.data_f);
                [~,b1]=lp_1_2(app.data_f);


                if isempty(app.zf1)
                    [temp_vot_11,app.zf1] = filter(b,1,temp_vot_1);
                    [temp_vot_21,app.zf2] = filter(b,1,temp_vot_2);
                    [temp_vot_31,app.zf3] = filter(b,1,temp_vot_3);
                    [temp_vot_41,app.zf4] = filter(b,1,temp_vot_4);
                    [temp_vot_51,app.zf5] = filter(b1,1,temp_vot_5);
                    [temp_vot_61,app.zf6] = filter(b1,1,temp_vot_6);
                else 
                    [temp_vot_11,app.zf1] = filter(b,1,temp_vot_1,app.zf1);
                    [temp_vot_21,app.zf2] = filter(b,1,temp_vot_2,app.zf2);
                    [temp_vot_31,app.zf3] = filter(b,1,temp_vot_3,app.zf3);
                    [temp_vot_41,app.zf4] = filter(b,1,temp_vot_4,app.zf4);
                    [temp_vot_51,app.zf5] = filter(b1,1,temp_vot_5,app.zf5);
                    [temp_vot_61,app.zf6] = filter(b1,1,temp_vot_6,app.zf6);
                end
    
                %Abscissa assignment
                app.xData = app.xData + app.data_len / app.data_f;

                app.real_vot_1 = temp_vot_11;
                app.real_vot_2 = temp_vot_21;
                app.real_vot_3 = temp_vot_31;
                app.real_vot_4 = temp_vot_41;
                app.real_vot_5 = temp_vot_51;
                app.real_vot_6 = temp_vot_61;
                if app.integ_eff ~= 0
                    app.vot_integ_1(end) = 0;
                    app.vot_integ_2(end) = 0;
                    app.vot_integ_3(end) = 0;
                    app.vot_integ_4(end) = 0;
                    app.vot_integ_5(end) = 0;
                    app.vot_integ_6(end) = 0;
                end
%               refresh
                if length(app.vot_integ_1) < 2
                    app.vot_integ_1 = ones(app.data_len,1);
                    app.vot_integ_2 = ones(app.data_len,1);
                    app.vot_integ_3 = ones(app.data_len,1);
                    app.vot_integ_4 = ones(app.data_len,1);
                    app.vot_integ_5 = ones(app.data_len,1);
                    app.vot_integ_6 = ones(app.data_len,1);
                end
                temp_integ_1 = app.vot_integ_1;
                temp_integ_2 = app.vot_integ_2;
                temp_integ_3 = app.vot_integ_3;
                temp_integ_4 = app.vot_integ_4;
                
                
                app.vot_integ_1 = ones(app.data_len,1)*app.vot_integ_1(end);
                app.vot_integ_2 = ones(app.data_len,1)*app.vot_integ_2(end);
                app.vot_integ_3 = ones(app.data_len,1)*app.vot_integ_3(end);
                app.vot_integ_4 = ones(app.data_len,1)*app.vot_integ_4(end);
                app.vot_integ_5 = ones(app.data_len,1)*app.vot_integ_5(end);
                app.vot_integ_6 = ones(app.data_len,1)*app.vot_integ_6(end);
                temp_len = length(temp_integ_1);
                integ_thr_1 = 4;
                integ_thr_2 = 2;
                integ_time = 0.02*app.data_f;
   
                app.V1 = [];
                app.V2 = [];
                app.V3 = [];
                app.V4 = [];
                app.V5 = [];
                app.V6 = [];
                for i = 1:app.data_len
                    app.mean_i(1) = app.mean_i(1)+(app.real_vot_1(i)-app.mean_i(1)) / 10000 * app.integ_eff;
                    app.mean_i(2) = app.mean_i(2)+(app.real_vot_2(i)-app.mean_i(2)) / 10000 * app.integ_eff;
                    app.mean_i(3) = app.mean_i(3)+(app.real_vot_3(i)-app.mean_i(3)) / 10000 * app.integ_eff;
                    app.mean_i(4) = app.mean_i(4)+(app.real_vot_4(i)-app.mean_i(4)) / 10000 * app.integ_eff;
                    app.mean_i(5) = app.mean_i(5)+(app.real_vot_5(i)-app.mean_i(5)) / 10000 * app.integ_eff;
                    app.mean_i(6) = app.mean_i(6)+(app.real_vot_6(i)-app.mean_i(6)) / 10000 * app.integ_eff;
                    if app.real_vot_1(i)-app.mean_i(1) < 0.01 && app.real_vot_1(i)-app.mean_i(1)>-0.01
                        app.vot_integ_1(i:end) = app.vot_integ_1(i:end);
                        app.vot_integ_2(i:end) = app.vot_integ_2(i:end);
                        app.vot_integ_3(i:end) = app.vot_integ_3(i:end);
                        app.vot_integ_4(i:end) = app.vot_integ_4(i:end); 
                        app.vot_integ_5(i:end) = app.vot_integ_5(i:end); 
                        app.vot_integ_6(i:end) = app.vot_integ_6(i:end); 
                    else
                        app.vot_integ_1(i:end) = app.vot_integ_1(i:end)+app.real_vot_1(i)-app.mean_i(1);
                        app.vot_integ_2(i:end) = app.vot_integ_2(i:end)+app.real_vot_2(i)-app.mean_i(2);
                        app.vot_integ_3(i:end) = app.vot_integ_3(i:end)+app.real_vot_3(i)-app.mean_i(3);
                        app.vot_integ_4(i:end) = app.vot_integ_4(i:end)+app.real_vot_4(i)-app.mean_i(4);
                        app.vot_integ_5(i:end) = app.vot_integ_5(i:end)+app.real_vot_5(i)-app.mean_i(5);
                        app.vot_integ_6(i:end) = app.vot_integ_6(i:end)+app.real_vot_6(i)-app.mean_i(6);
                    end
                    app.integ_all(i) = app.vot_integ_1(i)+app.vot_integ_2(i)+app.vot_integ_3(i)+app.vot_integ_4(i);
                    if i < integ_time
                        if app.vot_integ_1(i)+app.vot_integ_2(i)+app.vot_integ_3(i)+app.vot_integ_4(i)...
                                -temp_integ_1(temp_len-integ_time+1)-temp_integ_2(temp_len-integ_time+1)-temp_integ_3(temp_len-integ_time+1)-temp_integ_4(temp_len-integ_time+1)>integ_thr_1
                            app.integ_eff = 0;
                        end
                        if app.integ_eff == 0 && app.vot_integ_1(i)+app.vot_integ_2(i)+app.vot_integ_3(i)+app.vot_integ_4(i)...
                                -temp_integ_1(temp_len-integ_time+1)-temp_integ_2(temp_len-integ_time+1)-temp_integ_3(temp_len-integ_time+1)-temp_integ_4(temp_len-integ_time+1)<-integ_thr_2
                            app.integ_eff = 5;
                        end                    
                    else
                        if app.vot_integ_1(i)+app.vot_integ_2(i)+app.vot_integ_3(i)+app.vot_integ_4(i)...
                            -(app.vot_integ_1(i-integ_time+1)+app.vot_integ_2(i-integ_time+1)+app.vot_integ_3(i-integ_time+1)+app.vot_integ_4(i-integ_time+1))>integ_thr_1
                        app.integ_eff = 0;
                        end
                        if app.integ_eff == 0 && app.vot_integ_1(i)+app.vot_integ_2(i)+app.vot_integ_3(i)+app.vot_integ_4(i)...
                                -(app.vot_integ_1(i-integ_time+1)+app.vot_integ_2(i-integ_time+1)+app.vot_integ_3(i-integ_time+1)+app.vot_integ_4(i-integ_time+1))<-integ_thr_2
                            app.integ_eff = 5;
                        end
                        
                    end
                    
                    if app.vot_integ_1(i)+app.vot_integ_2(i)+app.vot_integ_3(i)+app.vot_integ_4(i)<0
                        app.vot_integ_1(i) = 0;
                        app.vot_integ_2(i) = 0;
                        app.vot_integ_3(i) = 0;
                        app.vot_integ_4(i) = 0;
                        app.vot_integ_5(i) = 0;
                        app.vot_integ_6(i) = 0;
                    end
                    app.V1(i) = app.real_vot_1(i)-app.mean_i(1);
                    app.V2(i) = app.real_vot_2(i)-app.mean_i(2);
                    app.V3(i) = app.real_vot_3(i)-app.mean_i(3);
                    app.V4(i) = app.real_vot_4(i)-app.mean_i(4);
                    app.V5(i) = app.real_vot_5(i)-app.mean_i(5);
                    app.V6(i) = app.real_vot_6(i)-app.mean_i(6);
                end
                app.countOfCsv = app.countOfCsv+1;
                app.data_len_total = app.data_len_total + app.data_len;
                if app.countOfCsv == 15
                    app.mean_i(1) = (app.mean_i(1) + app.vot_integ_1(end)) / app.data_len_total;
                    app.mean_i(2) = (app.mean_i(2) + app.vot_integ_2(end)) / app.data_len_total;
                    app.mean_i(3) = (app.mean_i(3) + app.vot_integ_3(end)) / app.data_len_total;
                    app.mean_i(4) = (app.mean_i(4) + app.vot_integ_4(end)) / app.data_len_total;
                    app.mean_i(5) = (app.mean_i(5) + app.vot_integ_5(end)) / app.data_len_total;
                    app.mean_i(6) = (app.mean_i(6) + app.vot_integ_6(end)) / app.data_len_total;
                    app.vot_integ_1(end) = 0;
                    app.vot_integ_2(end) = 0;
                    app.vot_integ_3(end) = 0;
                    app.vot_integ_4(end) = 0;
                    app.vot_integ_5(end) = 0;
                    app.vot_integ_6(end) = 0;
                    app.integ_eff = 4;
                end

            end
        end
        function closepy(app)
            
            py.ad7606_read.close_device(app.byIndex);

        end


        function peaks_num = right_peak(app,peaks_right,peaks_index_right,peaks_index_wrong,peaks_data_wrong,scope_num)
            peaks_num = [];
            for k = 1:length(peaks_right)

                right_num = peaks_index_right(peaks_right(k));
                
                sub_index = abs(peaks_index_wrong-right_num);
                [~,min_num] = min(sub_index);
                if min_num-scope_num>0
                    start_peak = min_num-scope_num;
                else
                    start_peak = 1;
                end
                if min_num+scope_num<=length(peaks_data_wrong)
                    end_peak = min_num+scope_num;
                else
                    end_peak = length(peaks_data_wrong);
                end
                
                if min_num+scope_num>length(peaks_data_wrong)   

                    [~,max_num] = max(peaks_data_wrong(start_peak:end));
                    peaks_num(k) = min_num;
                elseif min_num-scope_num<1
                    [~,max_num] = max(peaks_data_wrong(1:end_peak));
                    peaks_num(k) = min_num;
                else
                    [~,max_num] = max(peaks_data_wrong(start_peak:end_peak));
                    peaks_num(k) = max_num+min_num-scope_num-1;
                end
                             
            end
%             if isempty(peaks_num)
%                 return
%             end
        
        end
        
        function peaks = peak_find(app,peaks_index,peaks_data,sco_num)
            last_peak = 0;
            peak_num = 1;
            peaks = [];
            for j = 1:length(peaks_index)
                if last_peak ==0
                    last_peak = j;
                elseif peaks_index(j)-peaks_index(last_peak)>sco_num
                    peaks(peak_num) = peaks_index(last_peak);
                    peak_num = peak_num+1;
                    last_peak = j;
                else
                    if peaks_data(j)>peaks_data(last_peak)
                        last_peak = j;
        
                    end
                           
                end
          
            end
            if isempty(peaks)
                return
            end

        end        

        
        function [x,y] = locword(~,r1,r2)
            [c1,~]=contour(app.X,app.Y,app.Mat1,[r1,r2]);
            [c2,~]=contour(app.X,app.Y,app.Mat1,[r1,r2]);
            p = intp2(c1,c2);
            if ~isempty(p) %If the intersection exists
                x = p(1,1);
                y = p(1,2);
            else %If the intersection doesn't exist
                x = 0;
                y = 0;
            end

        end

        
        function reset_pic(app)
            app.Image_act.ImageSource = 'picture/ori_act.png';
            app.Image_position.ImageSource = 'picture/ori_position.png';

            
        end
        
        
        function plot_position(app)
            if app.word_x == 1 && app.word_y == 1 && app.ifheavy==1
                app.Image_position.ImageSource = 'picture/11_1.png';
            elseif app.word_x == 1 && app.word_y == 1 && app.ifheavy==2
                app.Image_position.ImageSource = 'picture/11_2.png';
            elseif app.word_x == 1 && app.word_y == 2 && app.ifheavy==1
                app.Image_position.ImageSource = 'picture/12_1.png';
            elseif app.word_x == 1 && app.word_y == 2 && app.ifheavy==2
                app.Image_position.ImageSource = 'picture/12_2.png';
            elseif app.word_x == 1 && app.word_y == 3 && app.ifheavy==1
                app.Image_position.ImageSource = 'picture/13_1.png';
            elseif app.word_x == 1 && app.word_y == 3 && app.ifheavy==2
                app.Image_position.ImageSource = 'picture/13_2.png';
            elseif app.word_x == 2 && app.word_y == 1 && app.ifheavy==1
                app.Image_position.ImageSource = 'picture/21_1.png';
            elseif app.word_x == 2 && app.word_y == 1 && app.ifheavy==2
                app.Image_position.ImageSource = 'picture/21_2.png';
            elseif app.word_x == 2 && app.word_y == 2 && app.ifheavy==1
                app.Image_position.ImageSource = 'picture/22_1.png';
            elseif app.word_x == 2 && app.word_y == 2 && app.ifheavy==2
                app.Image_position.ImageSource = 'picture/22_2.png';
            elseif app.word_x == 2 && app.word_y == 3 && app.ifheavy==1
                app.Image_position.ImageSource = 'picture/23_1.png';
            elseif app.word_x == 2 && app.word_y == 3 && app.ifheavy==2
                app.Image_position.ImageSource = 'picture/23_2.png';
            elseif app.word_x == 3 && app.word_y == 1 && app.ifheavy==1
                app.Image_position.ImageSource = 'picture/31_1.png';
            elseif app.word_x == 3 && app.word_y == 1 && app.ifheavy==2
                app.Image_position.ImageSource = 'picture/31_2.png';
            elseif app.word_x == 3 && app.word_y == 2 && app.ifheavy==1
                app.Image_position.ImageSource = 'picture/32_1.png';
            elseif app.word_x == 3 && app.word_y == 2 && app.ifheavy==2
                app.Image_position.ImageSource = 'picture/32_2.png';
            elseif app.word_x == 3 && app.word_y == 3 && app.ifheavy==1
                app.Image_position.ImageSource = 'picture/33_1.png';
            elseif app.word_x == 3 && app.word_y == 3 && app.ifheavy==2
                app.Image_position.ImageSource = 'picture/33_2.png';
            end
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.xData = -(app.bufflen-1)/app.data_f:1/app.data_f:0;

            app.h_Ori = animatedline(app.ori_vot, MaximumNumPoints=app.bufflen,Color=[197,90,17]./255);
            app.h_Ori2 = animatedline(app.ori_vot, MaximumNumPoints=app.bufflen,Color=[112,176,121]./255);
            app.h_Ori3 = animatedline(app.ori_vot, MaximumNumPoints=app.bufflen,Color='r');
            app.h_Ori4 = animatedline(app.ori_vot, MaximumNumPoints=app.bufflen,Color='b');
            app.h_Ori5 = animatedline(app.ori_vot, MaximumNumPoints=app.bufflen,Color=[0,0,0]);
            app.ori_vot.YLim=[-0.2,1];

            app.h_R1 = animatedline(app.catch_vot, MaximumNumPoints=1201,Color=[0,0,0]);
            app.catch_vot.XLim=[1,1200];
            app.catch_vot.YLim=[-0.4,0.8];
            
            app.Mat1=".\equline\12_1234.mat";
            app.Mat2=".\equline\14_1234.mat";
            addpath(".\picture")
            reset_pic(app)
        end

        % Button pushed function: stopButton
        function stopButtonPushed(app, event)
            stop(app.timer_id);
            delete(app.timer_id);
            closepy(app);
            app.dog_ble = [];
            app.dog_act = [];


        end

        % Callback function
        function startsaveButtonPushed(app, event)
            app.startsave = 1;
            app.endsave = 0;
            app.V1_all = [];
            app.V2_all = [];
            app.V3_all = [];
            app.V4_all = [];
            app.V5_all = [];
            app.V6_all = [];
            app.xdata_save = [];
        end

        % Callback function
        function endsaveButtonPushed(app, event)
            app.endsave = 1;
            data_V1 = app.V1_all;
            data_V2 = app.V2_all;
            data_V3 = app.V3_all;
            data_V4 = app.V4_all;
            data_V5 = app.V5_all;
            data_V6 = app.V6_all;
            data_x  = app.xdata_save;
            save("act_train/ori_data/temp_V1.mat","data_V1");
            save("act_train/ori_data/temp_V2.mat","data_V2");
            save("act_train/ori_data/temp_V3.mat","data_V3");
            save("act_train/ori_data/temp_V4.mat","data_V4");
            save("act_train/ori_data/temp_V5.mat","data_V5");
            save("act_train/ori_data/temp_V5.mat","data_V6");
            save("act_train/ori_data/temp_data_x.mat","data_x");
        end

        % Button pushed function: connectButton
        function linkdogButtonPushed(app, event)
            blelist("Name","PetoiBLE-2500")
            app.dog_ble = ble("567687172500");
            app.dog_act = characteristic(app.dog_ble,"FFE0","FFE1");

        end

        % Button pushed function: startButton
        function startButtonPushed(app, event)
            openAd7606(app);

            app.net = importdata("Result_net.mat");
            app.timer_id = timer ('TimerFcn',{@app.adc_timer},'ExecutionMode','fixedDelay','Period',0.1);
            app.timer_id.BusyMode = 'queue';
            start(app.timer_id);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.GridLayout3 = uigridlayout(app.Tab);
            app.Panel = uipanel(app.GridLayout3);
            app.Panel.Title = 'Panel';
            app.GridLayout2 = uigridlayout(app.Panel);
            app.GridLayout = uigridlayout(app.GridLayout2);
            app.ori_vot = uiaxes(app.GridLayout);
            title(app.ori_vot, 'Original signal')
            xlabel(app.ori_vot, 'Time (s)')
            ylabel(app.ori_vot, 'Voltage (V)')
            zlabel(app.ori_vot, 'Z')
            app.catch_vot = uiaxes(app.GridLayout);
            title(app.catch_vot, 'Captured Signal')
            xlabel(app.catch_vot, 'Time(s)')
            ylabel(app.catch_vot, 'Voltage (V)')
            zlabel(app.catch_vot, 'Z')
            app.Image_position = uiimage(app.GridLayout);
            app.startButton = uibutton(app.GridLayout, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Text = 'start';
            app.stopButton = uibutton(app.GridLayout, 'push');
            app.stopButton.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.stopButton.Text = 'stop';
            app.connectButton = uibutton(app.GridLayout, 'push');
            app.connectButton.ButtonPushedFcn = createCallbackFcn(app, @linkdogButtonPushed, true);
            app.connectButton.Text = 'connect';
            app.Image_act = uiimage(app.GridLayout);
            app.acttype = uibutton(app.GridLayout, 'push');
            app.actdog = uibutton(app.GridLayout, 'push');
            app.actdog.Text = 'Button2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dog_action_recognition

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end