classdef dog_affective_interaction < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        TabGroup             matlab.ui.container.TabGroup
        Tab                  matlab.ui.container.Tab
        GridLayout3          matlab.ui.container.GridLayout
        Panel                matlab.ui.container.Panel
        GridLayout2          matlab.ui.container.GridLayout
        GridLayout           matlab.ui.container.GridLayout
        Button               matlab.ui.control.Button
        Image                matlab.ui.control.Image
        ActionButton         matlab.ui.control.Button
        switch_state         matlab.ui.control.Button
        Image_emotion        matlab.ui.control.Image
        Image_dog            matlab.ui.control.Image
        Image_cloud          matlab.ui.control.Image
        UIAxes_conversation  matlab.ui.control.UIAxes
        Tab2                 matlab.ui.container.Tab
        actdog               matlab.ui.control.Button
        connectButton        matlab.ui.control.Button
        stopButton           matlab.ui.control.Button
        startButton          matlab.ui.control.Button
        catch_vot            matlab.ui.control.UIAxes
        ori_vot              matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        % Description
        ifUsepy=1;
        byIndex;
        real_vot_1=[];  real_vot_2=[];  real_vot_3=[];  real_vot_4=[];real_vot_5=[];real_vot_6=[];
        ori_vot_1 = []; ori_vot_2 = []; ori_vot_3 = []; ori_vot_4 = [];ori_vot_5 = [];ori_vot_6 = [];
        V1_all = [];V2_all=[];V3_all=[];V4_all=[];V5_all=[];V6_all=[];xdata_save=[];
        V1_acttest;V2_acttest;V3_acttest;V4_acttest;V5_acttest;V6_acttest;
        V1;V2;V3;V4;V5;V6;data_total;
        vot_integ_1 = 0;
        vot_integ_2 = 0;
        vot_integ_3 = 0;
        vot_integ_4 = 0;
        vot_integ_5 = 0;
        vot_integ_6 = 0;
        timer_id;
        xData = [];
        data_f = 1000;
        bufflen=10000;
        data_len=0;
        data_len_total=0;
        mean_num = 2000;
        zf1; zf2; zf3; zf4;zf5;zf6;
        zf11; zf21; zf31; zf41;zf51;zf61;
        zf12; zf22; zf32; zf42;zf52;zf62;
        Mat1 = [];
        Mat2 = [];
        w1;w2;mu;
        countOfCsv = 1;
        ydata;
        last_posi = 0;
        posi=0;
        csvlen=100;
        word_x;word_y;
        temp_x;temp_y;
        OutYall=[];
        noiseall=[];
        mean_i = 0*ones(6,1);
        lastpeak = 0;
        zStart = -1;
        zEnd = -1;
        fitresult;fitresult2;
        h_Ori; h_Ori2;h_Ori3; h_Ori4;h_Ori5;
        h_Integ;
        h_Integ1;h_Integ2;
        h_R1;h_R2;
        integ_eff = 0;
        thr = 0.06;
        tcmode = 1;
        integ_all;
        startsave=0;
        endsave = 0;
        thr_net = 0.06;
        YPred=[];
        timernum = 0;
        ifsave= 1;
        crest_index;
        showtime=0;
        showtime_1=0;
        showtime_2 = 0;
        net;
        nameData = ["knock","pat","pet","press","push","slap","tickle","touch"];
        dog_act;
        dog_ble;
        ifact=0;
        ifheavy=1;
        temper = 0;
        iftouch = 0;
        Ratio1;Ratio2;

        wxyy_API;        
        act_list = ["stretch","wave","sniff","jump","shake","rest","angry","coming","dig","nod","bark","shout","shaking","shook","run"];
        emotion_list = ["happy","joyful","attached","fondness","fond","nostalgic","soothing","kindness","kind","compassionate","affectionate","agitated","tender","serene","strained","weary","exhausted","overwhelmed","frustrated"];


        if_familiar = 0;
        if_morning = 0;
        text_pre = "'Stretch' signifies relaxation, 'wave' implies refusal, 'Sniff' indicates curiosity, 'jump' or a 'nod' conveys excitement, 'Shake' suggests fear, 'good boy' implies cleverness, 'rest' signifies boredom, " + ...
            "'angry' indicates anger. 'come here' conveys intimacy, 'dig' suggests playfulness. The dog will bark if necessery. Each of these expressions captures a distinct emotion or response in a non-verbal way. Now i need you to play my pet dog. "
        % First Meeting 1
        text_1 = "The dog is introverted and timid. When I pet him for the first time, identify his mood, match it to two actions mentioned earlier, and then describe what he's likely to do. " + ...
            "Please respond in English, keeping the puppy's perspective. Please keep the response under 80 words.";
        % First Meeting 2
        text_2 = "The dog is introverted and timid. When I tickle him for the first time, identify his mood, match it to two actions mentioned above, and then describe what he's likely to do. " + ...
            "Please respond in English, maintaining the puppy's perspective. Please keep the response under 80 words. Don't warp";
        %After getting familiar with the dog 1
        text_3 = "I've had him for a year, and he's outgoing now. When I pet him, identify his mood, match it to two actions mentioned above, and then describe what he's likely to do. " + ...
            "Please respond in English, maintaining the puppy's perspective. Please keep the response under 80 words. Don't warp";
        %After getting familiar with the dog 2
        text_4 =" I've had him for a year, and he's outgoing now. When I tickle him, identify his mood, match it to two actions mentioned above, and then describe what he's likely to do. " + ...
            "Please respond in English, maintaining the puppy's perspective. Please keep the response under 80 words. Don't warp";
        %hi little dog
        text_5 = "When we met for the first time, you're introverted and timid. I said hi little dog to you, how would you react? Match it to two actions mentioned above. Please reply me in English with a simulated dog tone. No more than 80 words. Don't warp"
        %come on
        text_6 = "We have been together for many years and you are a cheerful little dog who loves me very much. When I say 'come on' to you, how will you react? " + ...
            "Please use the puppy's expectation and choose the 2 or so actions I mentioned above to reply to me. Please answer in English. Less than 80 words. Don't warp."
        %before work
        text_7_1 = "We have been together for many years and you are a cheerful little dog who loves me very much. One morning, before leaving for work, I said goodbye to you and gave it a pat by a warm hand. Leave you at home. And my mood is " 
        text_7_2 = ". How will you react? Please use the puppy's expectation and choose the 2 or so actions I mentioned above to reply to me. Please answer in English. Less than 80 words. Don't warp";
        %after work, good temper
        text_8_1 = "We have been together for many years and you are a cheerful little dog who loves me very much. One evening, after a tiring day at work, I returns home. In the face of you running towards me happily, I patted you with my cold hand and said that I was back. And my mood is "
        text_8_2 = ". How will you react? Please use the puppy's expectation and choose the 2 or so actions I mentioned above to reply to me. Please answer in English. Less than 80 words. Don't warp";
        %after work, bad temper
        text_9_1 = "We have been together for many years and you are a cheerful little dog who loves me very much. However, one evening, after a very tiring day at work, I returns home. In the face of you running towards me happily, I pushed you away with my cold hands and said in a tired voice that I was back. And my mood is "
        text_9_2 = ". How will you react? Please use the puppy's expectation and choose the 2 or so actions I mentioned above to reply to me. Please answer in English. Less than 80 words. Don't warp";
        text_10 = "Imagine a scenario where there is a person who is lively, cheerful, and loves life, being positive. One morning, before leaving for work, this person said goodbye to his dog and gave it a pat by a warm hand. Leave you at home. " + ...
            "I need you to play the dog to analyze the owner's mood, summarize it in one word, and explain. Please answer in English. No line breaks. Less than 50 words.";
        text_11 = "Imagine a scenario where there is a person with a patient and compassionate personality. One evening, after a tiring day at work, he returns home. Faced with an approaching dog, he pat it by a cold hand lively. " + ...
            "I need you to play the role of this dog to analyze the owner's mood, summarize it in one word, and explain. Please respond in English. No line breaks. Less than 50 words.";
        text_12 = "Imagine a scenario where there is a person with a patient and compassionate personality. However, one evening, after a very tiring day at work, he returns home. Faced with an approaching dog, he impatiently pushes it away by a cold hand. " + ...
            "I need you to play the role of this dog to analyze the owner's mood, summarize it in one word, and explain. Please respond in English. No line breaks. Less than 50 words.";
        dog_output = [];
        row_num = 0;
        run_time = 0;
        voice_data= [];
        get_emotion;
    end
    
    methods (Access = public)
        function adc_timer(app, ~, ~)
            app.run_time = app.run_time +1;

          
            try
                readAd7606(app);
            catch MException
                disp(MException)
            end
            
            if app.data_len>0
                try
                    xdata = app.xData(app.bufflen-app.data_len+1:app.bufflen);
          
                    %output row data
                    addpoints(app.h_Ori,xdata,app.V1);
                    addpoints(app.h_Ori2,xdata,app.V2+0.2);
                    addpoints(app.h_Ori3,xdata,app.V3+0.4);
                    addpoints(app.h_Ori4,xdata,app.V4+0.6);
                    addpoints(app.h_Ori5,xdata,(app.V5-app.V6)*0.2+0.8);


                    app.data_total = app.V1+app.V2+app.V3+app.V4;
                    [peaks_data_total,peaks_index_total] = findpeaks(app.data_total);


                    app.V1_acttest = [app.V1_acttest,app.V1];
                    app.V2_acttest = [app.V2_acttest,app.V2];
                    app.V3_acttest = [app.V3_acttest,app.V3];
                    app.V4_acttest = [app.V4_acttest,app.V4];
                    single_length = length(app.V1);
                    
                    app.timernum = app.timernum+1;
                    app.showtime = app.showtime+1;
                    app.showtime_2 = app.showtime_2 +1;
                    app.showtime_1 = app.showtime_1+1;
                    %after act
                    if app.showtime>40 && app.ifact ==1
                        app.ifact = 0;
                        app.ifsave = 1;
                        app.ActionButton.Text = "And you...";
                        cla(app.UIAxes_conversation)
                        app.Image.ImageSource = "picture/emotion_ori.png";
                        app.Button.Text = "";
                    end
                    
                    if app.showtime_2>30
                        app.iftouch=0;
                    end
                    if app.showtime_1>30 && app.temper == 1
                        app.temper = 0;
                    end


                    peaks_find = find(peaks_data_total>app.thr_net);
                    peaks_index_find = peaks_index_total(peaks_find);
                    peaks_data_find = peaks_data_total(peaks_find);
                    

                    if length(peaks_data_find)>=1 && app.ifsave && app.temper==0 && app.run_time >= 100
                        
                        [~,max_index] = max(peaks_data_find);
                        app.showtime = 0;
                        app.showtime_2 = 0;
                        app.iftouch = 1;
                        app.crest_index = peaks_index_find(max_index)+length(app.V1_acttest)-single_length;
                        
                        app.ifsave = 0;
                        app.Button.Text = "";
%                         app.Image.ImageSource = "picture/emotion_think.png";
%                         app.Image_cloud.ImageSource = 'picture/think.png';
                    end
                    num1 = 300;
                    num2 = 900;
                    %get valid wave
                    if length(app.V1_acttest)> app.crest_index+num2 && app.ifsave == 0 && app.ifact ~=1 && app.temper==0 && app.run_time >= 100
                        app.ifact = 1;
                        app.showtime = 0;
                        app.showtime_2 = 0;
                        app.iftouch = 1;
                        right_sco = app.crest_index-num1:app.crest_index+num2;
                        temp_data = app.V1_acttest(right_sco)+app.V2_acttest(right_sco)+app.V3_acttest(right_sco)+app.V4_acttest(right_sco);
                        
                        clearpoints(app.h_R1)
                        addpoints(app.h_R1,1:length(temp_data),temp_data);
 
                        [~,~,~,~,~,~,Qout,V_Seg] = app.saveFileFunc(temp_data, app.data_f);
                        gap = 0;

                        [peaks_data_right,peaks_index_right] = findpeaks(V_Seg);
   
                        peaks_right = find(peaks_data_right>app.thr);
                        vaild_peak = [];
                        for kk = 1:length(peaks_right)-1
                            if peaks_index_right(peaks_right(kk+1))-peaks_index_right(peaks_right(kk))>gap
                                vaild_peak = [vaild_peak,kk];
                            end
                        end
 

                        fb = cwtfilterbank(SignalLength=length(V_Seg), ...
                            SamplingFrequency=sampleRate, ...
                            VoicesPerOctave=12);
                        cfs = abs(fb.wt(V_Seg));
                    
                        X_Q=1:length(Qout);
                        Qout = Qout-(Qout(end)-Qout(1))/length(Qout).*X_Q';
                        Q_max = max(Qout);  
                    
                        [m_cfs,n_cfs]=size(cfs);
                        cfs_save=zeros(m_cfs+30,n_cfs);
                    
                        cfs_save(1:30,:) = Q_max/1000; 
                        cfs_save(31:30+m_cfs,1:n_cfs)=cfs;
                    
                        im = ind2rgb(round(rescale(cfs_save,0,255)),jet(128));
                    
                        im = imresize(im,[224 224]);

                        
                        app.YPred = classify(app.net,im);
                        app.YPred=int32(app.YPred);


                        %scenario 1
                        if app.if_morning == 1
                            if app.YPred==2
                                app.ActionButton.Text = "You pat the dog by your warm hand and say goodbye";
                                response = app.wxyy_API.chat_once(app.text_10);

                                app.Image_cloud.ImageSource = 'picture/cloud.png';
                                emotion_analyse(app,response,app.emotion_list)
                                pause(3)
                                if ~isempty(app.get_emotion)
                                    text_input = app.text_pre+app.text_7_1+app.get_emotion+app.text_7_2;
                                    response_1 = app.wxyy_API.chat_once(text_input);
                                    output_text_new(app,response_1,app.act_list);
                                end

                            end
                        end
                        %scenario 2
                        if app.if_morning == 2
                            if app.YPred==18
                                disp("night1")
                                
                                app.ActionButton.Text = "You pat the dog by your cold hand and say 'I'm back'";
                                response = app.wxyy_API.chat_once(app.text_11);
                                app.Image_cloud.ImageSource = 'picture/cloud.png';
                                emotion_analyse(app,response,app.emotion_list)
                                pause(3)
                                if ~isempty(app.get_emotion)
                                    text_input = app.text_pre+app.text_8_1+app.get_emotion+app.text_8_2;
                                    response_1 = app.wxyy_API.chat_once(text_input);
                                    output_text_new(app,response_1,app.act_list);
                                end
                            end

                        end
                        %scenario 3
                        if app.if_morning == 0
                            if app.YPred==21
                                disp("night2")
                                
                                app.ActionButton.Text = "You push the dog by your cold hand and say 'I'm back'";
                                response = app.wxyy_API.chat_once(app.text_12);
                                app.Image_cloud.ImageSource = 'picture/cloud.png';
                                emotion_analyse(app,response,app.emotion_list)
                                pause(3)
                                if ~isempty(app.get_emotion)
                                    text_input = app.text_pre+app.text_9_1+app.get_emotion+app.text_9_2;
                                    response_1 = app.wxyy_API.chat_once(text_input);
                                    output_text_new(app,response_1,app.act_list);
                                end
                            end

                        end
                        
                    end
                    %temperature recognization
                    if max(abs(app.V1_acttest(end-5000:end)))<0.05 && max(abs(app.V2_acttest(end-5000:end)))<0.05&& max(abs(app.V3_acttest(end-5000:end)))<0.05&& max(abs(app.V4_acttest(end-5000:end)))<0.05&&app.temper == 0&&app.iftouch==0
                        if mean(app.V5-app.V6)<-0.1
                            disp('cold')
                            app.temper = 1;
                            app.showtime_1 = 0; 

                                   
                        elseif mean(app.V5-app.V6)>0.5
                            disp('hot')
                            app.temper = 1;
                            app.showtime_1 = 0; 

                            
                        end
                    end
                   
                    
                catch MException
%                      disp(MException);
%                      disp('峰值检测错误')
                end
   
            end


        end


    end
    
    methods (Access = private)
        function openAd7606(app)
            py.importlib.import_module('ad7606_read');
            obj = py.importlib.import_module('ad7606_read');
            py.importlib.reload(obj);
            P = py.sys.path; 
            if count(P,'./ad7606_read.py') == 0
               insert(P,int32(0),'./ad7606_read.py');
            end
            py.importlib.import_module('ad7606_read');
            delete(timerfind);
            app.byIndex = py.ad7606_read.openadc(int32(1000000/app.data_f));
            py.ad7606_read.adc_startread(app.byIndex);
        end

        function readAd7606(app)
            real_vot = double(py.ad7606_read.readadc_data(app.byIndex))';       
            data_len_r = length(real_vot);
            app.data_len = data_len_r / 8;
            if app.data_len > 0
                temp_vot_1 = zeros(app.data_len,1);
                temp_vot_2 = zeros(app.data_len,1);
                temp_vot_3 = zeros(app.data_len,1);
                temp_vot_4 = zeros(app.data_len,1);
                temp_vot_5 = zeros(app.data_len,1);
                temp_vot_6 = zeros(app.data_len,1);
                for i = 1:app.data_len
                    temp_vot_1(i) = real_vot(i*8-7);
                    temp_vot_2(i) = real_vot(i*8-6);
                    temp_vot_3(i) = real_vot(i*8-5);
                    temp_vot_4(i) = real_vot(i*8-4);
                    temp_vot_5(i) = real_vot(i*8-3);
                    temp_vot_6(i) = real_vot(i*8-2);
                end


                [~,b]=lp_20_50(app.data_f);
                [~,b1]=lp_1_2(app.data_f);


                if isempty(app.zf1)
                    [temp_vot_11,app.zf1] = filter(b,1,temp_vot_1);
                    [temp_vot_21,app.zf2] = filter(b,1,temp_vot_2);
                    [temp_vot_31,app.zf3] = filter(b,1,temp_vot_3);
                    [temp_vot_41,app.zf4] = filter(b,1,temp_vot_4);
                    [temp_vot_51,app.zf5] = filter(b1,1,temp_vot_5);
                    [temp_vot_61,app.zf6] = filter(b1,1,temp_vot_6);
                else 
                    [temp_vot_11,app.zf1] = filter(b,1,temp_vot_1,app.zf1);
                    [temp_vot_21,app.zf2] = filter(b,1,temp_vot_2,app.zf2);
                    [temp_vot_31,app.zf3] = filter(b,1,temp_vot_3,app.zf3);
                    [temp_vot_41,app.zf4] = filter(b,1,temp_vot_4,app.zf4);
                    [temp_vot_51,app.zf5] = filter(b1,1,temp_vot_5,app.zf5);
                    [temp_vot_61,app.zf6] = filter(b1,1,temp_vot_6,app.zf6);
                end
    
                %Abscissa assignment
                app.xData = app.xData + app.data_len / app.data_f;
                % Fill new array

                app.real_vot_1 = temp_vot_11;
                app.real_vot_2 = temp_vot_21;
                app.real_vot_3 = temp_vot_31;
                app.real_vot_4 = temp_vot_41;
                app.real_vot_5 = temp_vot_51;
                app.real_vot_6 = temp_vot_61;
                if app.integ_eff ~= 0
                    app.vot_integ_1(end) = 0;
                    app.vot_integ_2(end) = 0;
                    app.vot_integ_3(end) = 0;
                    app.vot_integ_4(end) = 0;
                    app.vot_integ_5(end) = 0;
                    app.vot_integ_6(end) = 0;
                end
%                Integral array update
                if length(app.vot_integ_1) < 2
                    app.vot_integ_1 = ones(app.data_len,1);
                    app.vot_integ_2 = ones(app.data_len,1);
                    app.vot_integ_3 = ones(app.data_len,1);
                    app.vot_integ_4 = ones(app.data_len,1);
                    app.vot_integ_5 = ones(app.data_len,1);
                    app.vot_integ_6 = ones(app.data_len,1);
                end
                temp_integ_1 = app.vot_integ_1;
                temp_integ_2 = app.vot_integ_2;
                temp_integ_3 = app.vot_integ_3;
                temp_integ_4 = app.vot_integ_4;
                
                
                app.vot_integ_1 = ones(app.data_len,1)*app.vot_integ_1(end);
                app.vot_integ_2 = ones(app.data_len,1)*app.vot_integ_2(end);
                app.vot_integ_3 = ones(app.data_len,1)*app.vot_integ_3(end);
                app.vot_integ_4 = ones(app.data_len,1)*app.vot_integ_4(end);
                app.vot_integ_5 = ones(app.data_len,1)*app.vot_integ_5(end);
                app.vot_integ_6 = ones(app.data_len,1)*app.vot_integ_6(end);
                temp_len = length(temp_integ_1);
                integ_thr_1 = 4;
                integ_thr_2 = 2;
                integ_time = 0.02*app.data_f;
   
                app.V1 = [];
                app.V2 = [];
                app.V3 = [];
                app.V4 = [];
                app.V5 = [];
                app.V6 = [];
                for i = 1:app.data_len
                    app.mean_i(1) = app.mean_i(1)+(app.real_vot_1(i)-app.mean_i(1)) / 10000 * app.integ_eff;
                    app.mean_i(2) = app.mean_i(2)+(app.real_vot_2(i)-app.mean_i(2)) / 10000 * app.integ_eff;
                    app.mean_i(3) = app.mean_i(3)+(app.real_vot_3(i)-app.mean_i(3)) / 10000 * app.integ_eff;
                    app.mean_i(4) = app.mean_i(4)+(app.real_vot_4(i)-app.mean_i(4)) / 10000 * app.integ_eff;
                    app.mean_i(5) = app.mean_i(5)+(app.real_vot_5(i)-app.mean_i(5)) / 10000 * app.integ_eff;
                    app.mean_i(6) = app.mean_i(6)+(app.real_vot_6(i)-app.mean_i(6)) / 10000 * app.integ_eff;
                    if app.real_vot_1(i)-app.mean_i(1) < 0.01 && app.real_vot_1(i)-app.mean_i(1)>-0.01
                        app.vot_integ_1(i:end) = app.vot_integ_1(i:end);
                        app.vot_integ_2(i:end) = app.vot_integ_2(i:end);
                        app.vot_integ_3(i:end) = app.vot_integ_3(i:end);
                        app.vot_integ_4(i:end) = app.vot_integ_4(i:end); 
                        app.vot_integ_5(i:end) = app.vot_integ_5(i:end); 
                        app.vot_integ_6(i:end) = app.vot_integ_6(i:end); 
                    else
                        app.vot_integ_1(i:end) = app.vot_integ_1(i:end)+app.real_vot_1(i)-app.mean_i(1);
                        app.vot_integ_2(i:end) = app.vot_integ_2(i:end)+app.real_vot_2(i)-app.mean_i(2);
                        app.vot_integ_3(i:end) = app.vot_integ_3(i:end)+app.real_vot_3(i)-app.mean_i(3);
                        app.vot_integ_4(i:end) = app.vot_integ_4(i:end)+app.real_vot_4(i)-app.mean_i(4);
                        app.vot_integ_5(i:end) = app.vot_integ_5(i:end)+app.real_vot_5(i)-app.mean_i(5);
                        app.vot_integ_6(i:end) = app.vot_integ_6(i:end)+app.real_vot_6(i)-app.mean_i(6);
                    end
                    app.integ_all(i) = app.vot_integ_1(i)+app.vot_integ_2(i)+app.vot_integ_3(i)+app.vot_integ_4(i);
                    if i < integ_time
                        if app.vot_integ_1(i)+app.vot_integ_2(i)+app.vot_integ_3(i)+app.vot_integ_4(i)...
                                -temp_integ_1(temp_len-integ_time+1)-temp_integ_2(temp_len-integ_time+1)-temp_integ_3(temp_len-integ_time+1)-temp_integ_4(temp_len-integ_time+1)>integ_thr_1
                            app.integ_eff = 0;
                        end
                        if app.integ_eff == 0 && app.vot_integ_1(i)+app.vot_integ_2(i)+app.vot_integ_3(i)+app.vot_integ_4(i)...
                                -temp_integ_1(temp_len-integ_time+1)-temp_integ_2(temp_len-integ_time+1)-temp_integ_3(temp_len-integ_time+1)-temp_integ_4(temp_len-integ_time+1)<-integ_thr_2
                            app.integ_eff = 5;
                        end                    
                    else
                        if app.vot_integ_1(i)+app.vot_integ_2(i)+app.vot_integ_3(i)+app.vot_integ_4(i)...
                            -(app.vot_integ_1(i-integ_time+1)+app.vot_integ_2(i-integ_time+1)+app.vot_integ_3(i-integ_time+1)+app.vot_integ_4(i-integ_time+1))>integ_thr_1
                        app.integ_eff = 0;
                        end
                        if app.integ_eff == 0 && app.vot_integ_1(i)+app.vot_integ_2(i)+app.vot_integ_3(i)+app.vot_integ_4(i)...
                                -(app.vot_integ_1(i-integ_time+1)+app.vot_integ_2(i-integ_time+1)+app.vot_integ_3(i-integ_time+1)+app.vot_integ_4(i-integ_time+1))<-integ_thr_2
                            app.integ_eff = 5;
                        end
                        
                    end
                    
                    if app.vot_integ_1(i)+app.vot_integ_2(i)+app.vot_integ_3(i)+app.vot_integ_4(i)<0
                        app.vot_integ_1(i) = 0;
                        app.vot_integ_2(i) = 0;
                        app.vot_integ_3(i) = 0;
                        app.vot_integ_4(i) = 0;
                        app.vot_integ_5(i) = 0;
                        app.vot_integ_6(i) = 0;
                    end
                    app.V1(i) = app.real_vot_1(i)-app.mean_i(1);
                    app.V2(i) = app.real_vot_2(i)-app.mean_i(2);
                    app.V3(i) = app.real_vot_3(i)-app.mean_i(3);
                    app.V4(i) = app.real_vot_4(i)-app.mean_i(4);
                    app.V5(i) = app.real_vot_5(i)-app.mean_i(5);
                    app.V6(i) = app.real_vot_6(i)-app.mean_i(6);
                end
                app.countOfCsv = app.countOfCsv+1;
                app.data_len_total = app.data_len_total + app.data_len;
                if app.countOfCsv == 15
                    app.mean_i(1) = (app.mean_i(1) + app.vot_integ_1(end)) / app.data_len_total;
                    app.mean_i(2) = (app.mean_i(2) + app.vot_integ_2(end)) / app.data_len_total;
                    app.mean_i(3) = (app.mean_i(3) + app.vot_integ_3(end)) / app.data_len_total;
                    app.mean_i(4) = (app.mean_i(4) + app.vot_integ_4(end)) / app.data_len_total;
                    app.mean_i(5) = (app.mean_i(5) + app.vot_integ_5(end)) / app.data_len_total;
                    app.mean_i(6) = (app.mean_i(6) + app.vot_integ_6(end)) / app.data_len_total;
                    app.vot_integ_1(end) = 0;
                    app.vot_integ_2(end) = 0;
                    app.vot_integ_3(end) = 0;
                    app.vot_integ_4(end) = 0;
                    app.vot_integ_5(end) = 0;
                    app.vot_integ_6(end) = 0;
                    app.integ_eff = 4;
                end

            end
        end
        function closepy(app)
            
            py.ad7606_read.close_device(app.byIndex);

        end


        function dog_control(app,action)
            %act_list = ["stretch","wave","sniff","jump","shake","rest","angry","come","coming","dig","nod","bark","shout"];

            switch action
                case "stretch"
                    write(app.dog_act,"kstr")
                    
                case "wave"
                    write(app.dog_act,"kwh")
                case "sniff"
                    write(app.dog_act,"ksnf")
                case "jump"
                    write(app.dog_act,"kjmp")
                case "shake"
                    write(app.dog_act,"kshk")
                case "come"
                    write(app.dog_act,"kcmh")
                case "rest"
                    write(app.dog_act,"krest")
                case "angry"
                    write(app.dog_act,"kang")
                case "check"
                    write(app.dog_act,"kck")
                case "coming"
                    write(app.dog_act,"kcmh")
                case "dig"
                    write(app.dog_act,"kdg")
                
                case "nod"
                    write(app.dog_act,"knd")
                case "bark"
                    write(app.dog_act,"|")
                    pause(0.4)
                    write(app.dog_act,"|")
                case "shout"
                    write(app.dog_act,"|")
                    pause(0.4)
                    write(app.dog_act,"|")
                case shaking
                    write(app.dog_act,"kshk")
                case shook
                    write(app.dog_act,"kshk")
                case run
                    write(app.dog_act,'kcmh')
                     
            end
            
        end
        
        function [keyword_list,keyword_index]=extractKeywords(app,text,keywordList)
            % Converts text to lower case for case-insensitive matching
            text = lower(text);
            keyword_list = [];
            keyword_index = [];
            % Iterate through the list of keywords
            for i = 1:length(keywordList)
                keyword = lower(keywordList{i});
                
                % Find the position of the keyword in the text
                keywordIndices = strfind(text, keyword);
                
                % If found, output the keyword and its location
                if ~isempty(keywordIndices)
                    keyword_list = [keyword_list," ",keyword];
                    keyword_index = [keyword_index,keywordIndices];
                end
            end
        end

        function [T,F,Pout,FV1_ROI,Pout2,Xout,Qout,V_Seg] = saveFileFunc(app,V_read, sampleRate)
        
            frequencyLimits = [0 1000]; % Hz
            timeResolution = 0.1; % 秒
            overlapPercent = 50;
        
        
            Q_read = zeros(length(V_read),1);
            for i = 2:length(V_read)
                Q_read(i) = Q_read(i-1)+V_read(i);
            end
            for i = 2:length(V_read)
                Q_read(i) = Q_read(i) - Q_read(end)./length(V_read)*i;
            end
        
        
            timeValues_read = (0:length(V_read)-1).'/sampleRate;
            
            % Draw time-frequency diagram
            [P1,F,T] = pspectrum(V_read,timeValues_read, ...
                'spectrogram', ...
                'FrequencyLimits',frequencyLimits, ...
                'TimeResolution',timeResolution, ...
                'OverlapPercent',overlapPercent);
            
            % Draw FFT spectrum
            [PV1_ROI, FV1_ROI] = pspectrum(V_read,sampleRate, ...
                'FrequencyLimits',frequencyLimits);
            %subplot(4,1,1)
            %surface(T,F,10*log10(P1))
            %shading interp
            %colormap(jet)
            %colorbar
            %clim([-100 -40])
        
            % Save time-frequency diagram
            Pout = 10*log10(P1);
            %subplot(4,1,2)
            %plot(FV1_ROI, 10*log10(PV1_ROI))
            %ylim([-100 -20])
        
            % Save FFT spectrum
            Pout2 = 10*log10(PV1_ROI);
            
            % Draw charge
            % subplot(4,1,3)
            %plot(Q_read-min(Q_read))
            
            % Draw voltage
            Xout = (1:length(V_read)) ./ 5000;
            %subplot(4,1,4)
            %plot(Xout,V_read)
            % Save charge
            Qout = Q_read-min(Q_read);
            V_Seg = V_read;
        
        end
        
        function wxyy(app)
            py.importlib.import_module('useAPI');
            obj = py.importlib.import_module('useAPI');
            py.importlib.reload(obj);
            P = py.sys.path; 
            if count(P,'./useAPI.py') == 0
               insert(P,int32(0),'./useAPI.py');
            end
            
            app.wxyy_API = py.importlib.import_module('useAPI');
        end
        
        function output_text(app,text_output)
            hold(app.UIAxes_conversation,"off")
            cla(app.UIAxes_conversation)
            length_row = 35;
            
            text_output = char(text_output);
%             text_output = lower(text_output);
            Font_size_row = 0.01855;
            Font_size_col = 0.05;
            for i = 1:ceil(length(text_output)/length_row)
                if i*length_row<length(text_output)
                    text_temp = text_output((i-1)*length_row+1:i*length_row);
                else
                    text_temp = text_output((i-1)*length_row+1:end);
                end
                text(app.UIAxes_conversation,0.17,0.8-i*Font_size_col,text_temp,FontName="Consolas",FontSize=24);
                hold(app.UIAxes_conversation,"on")
            end
            [keyword_list,keyword_index]=extractKeywords(app,text_output,app.act_list);

            if ~isempty(keyword_index)
                [key_start,key_order] = sort(keyword_index);

                keyword_reallist = cell(1,length(key_order));
                for i = 1:length(key_order)
                    keyword_reallist{key_order(i)} = keyword_list(2*i);
                end
                disp(keyword_reallist)
                

                for i = 1:length(keyword_index)
                    keyword_col =mod(keyword_index(i),length_row);
                    keyword_row = (keyword_index(i)-keyword_col)/length_row;
                    
                    char_keyword = char(keyword_list(2*i));
                    if keyword_col == 0
                        keyword_1 = char_keyword(1);
                        keyword_2 = char_keyword(2:end);
                        text(app.UIAxes_conversation,0.17+Font_size_row*34,0.75-Font_size_col*(keyword_row-1),keyword_1,FontName="Consolas",FontSize=24,FontWeight = 'bold',Color='r');
                        text(app.UIAxes_conversation,0.17,0.75-Font_size_col*(keyword_row),keyword_2,FontName="Consolas",FontSize=24,FontWeight = 'bold',Color='r');

                        hold(app.UIAxes_conversation,"on")

                    elseif keyword_col+length(char_keyword)<length_row
                        text(app.UIAxes_conversation,0.17+Font_size_row*(keyword_col-1),0.75-Font_size_col*keyword_row,keyword_list(2*i),FontName="Consolas",FontSize=24,FontWeight = 'bold',Color='r');
                        
                        hold(app.UIAxes_conversation,"on")

                    else
                        depart_num1 = keyword_col+length(char(keyword_list(2*i)))-length_row;
                        depart_num2 = length(char(keyword_list(2*i)))-depart_num1;
                        keyword_1 = char_keyword(1:depart_num2+1);
                        keyword_2 = char_keyword(depart_num2+2:end);
                        text(app.UIAxes_conversation,0.17+Font_size_row*(keyword_col-1),0.75-Font_size_col*keyword_row,keyword_1,FontName="Consolas",FontSize=24,FontWeight = 'bold',Color='r');
                        text(app.UIAxes_conversation,0.17,0.75-Font_size_col*(keyword_row+1),keyword_2,FontName="Consolas",FontSize=24,FontWeight = 'bold',Color='r');
                        hold(app.UIAxes_conversation,"on")
                    end
                   
                end
                %dog action
                if ~isempty(keyword_reallist)
                    for j = keyword_reallist
                        action = j{1};
                        disp(action)
                        dog_control(app,action);
                        pause(3)

                    end
                end
            end
            
        end
        
        function output_text_new(app,text_output,word_list)
            hold(app.UIAxes_conversation,"off")
            cla(app.UIAxes_conversation)
            length_row = 35;    

            text_output = char(text_output);
            text_output = strrep(text_output, '\n', '');
            text_output = strrep(text_output, '\r', '');
            
            % Split text into sentences
            sentences = strsplit(text_output, '. ');

            app.row_num = 0;
            for ii = 1:length(sentences)
                
                sentence = char(sentences{ii});
                if isempty(sentence)
                    break
                else
                    disp_sentence(app,sentence,length_row,word_list)
                    [keyword_list,keyword_index]=extractKeywords(app,lower(sentence),word_list);
                    [key_start,key_order] = sort(keyword_index);

                    keyword_reallist = cell(1,length(key_order));
                    for i = 1:length(key_order)
                        keyword_reallist{key_order(i)} = keyword_list(2*i);
                    end

                    
                    if ~isempty(keyword_reallist)
                        for j = keyword_reallist
                            action = j{1};
                            
                            dog_control(app,action);
                            pause(3)
    
                        end
                    end
                end


            end
 
                        
        end
        
        function disp_sentence(app,x,max_length,word_list)   

            Font_size_row = 0.01855;
            Font_size_col = 0.05;
            start_position = [0.17,0.8];

            record_keyword_index=[];
            record_keyword = [];
            record_rownum=[];
            record_temp_current_length=[];
            % Count words
            words = strsplit(x, ' ');
            num_words = numel(words);
            
            % Initializes a new text string
            new_text = '';
            
            % Line feed without cutting off words when maximum length is reached
            current_length = 0;
            
            for i = 1:num_words
                current_word = words{i};
                word_length = strlength(current_word);
                
                
                if current_length + word_length <= max_length
                    temp_current_length = current_length;
                    % Adds the word to the current line
                    new_text = [new_text, ' ', current_word];
                    current_length = current_length + word_length + 1; % 加上空格的长度  

                else 
                    temp_current_length = 0;

                    text(app.UIAxes_conversation,start_position(1),start_position(2)-Font_size_col*app.row_num, new_text,FontName="Consolas",FontSize=24);
                    hold(app.UIAxes_conversation,"on")
                   
                    % Line wrap when maximum length is reached
                    new_text = [' ',current_word];
                    current_length = strlength(current_word)+1; 
                    app.row_num = app.row_num+1;
                end
                
                [keyword,keyword_index]=extractKeywords(app,lower(current_word),word_list);
                if ~isempty(keyword) && keyword_index<=3
                    record_keyword_index = [record_keyword_index,keyword_index];
                    record_keyword = [record_keyword,keyword(2)];
                    record_rownum = [record_rownum,app.row_num];
                    record_temp_current_length = [record_temp_current_length,temp_current_length];
                end
               
            end
            if new_text(end) ~= '.'
                new_text = [new_text,'.'];
            end
            text(app.UIAxes_conversation, start_position(1), start_position(2)-Font_size_col*app.row_num, new_text,FontName="Consolas",FontSize=24);
            hold(app.UIAxes_conversation,"on")
            app.row_num = app.row_num+1;
            for i = 1:length(record_keyword_index)
                if record_keyword_index(i)+record_temp_current_length(i)<max_length


                    text(app.UIAxes_conversation,start_position(1)+Font_size_row*(record_keyword_index(i)+record_temp_current_length(i)),start_position(2)-Font_size_col*record_rownum(i),record_keyword(i),FontName="Consolas",FontSize=24,FontWeight = 'bold',Color='r');
                    hold(app.UIAxes_conversation,"on")
                else

                    text(app.UIAxes_conversation,start_position(1)+Font_size_row*(record_keyword_index(i)-1),start_position(2)-Font_size_col*record_rownum(i),record_keyword(i),FontName="Consolas",FontSize=24,FontWeight = 'bold',Color='r');
                    hold(app.UIAxes_conversation,"on")
                end
            end


        end
        
        
        function displayCharacteristicData(app,src,evt)
            [app.voice_data,~] = read(src,'oldest');
        end
        
        function emotion_disp(app,emotion)
            %'attached',"fondness","fond","soothing","agitated","tender","serene",
            % "strained","weary","exhausted","overwhelmed","frustrated"
            switch emotion
                case "joyful"
                    app.Button.Text = "joyful";
                    app.Image.ImageSource = "picture/emotion_3.png";
                case "happy"
                    app.Button.Text = "happy";
                    app.Image.ImageSource = "picture/emotion_3.png";
                case "attached"
                    app.Button.Text = "attached";
                    app.Image.ImageSource = "picture/emotion_3.png";
                case "fondness"
                    app.Button.Text = "fondness";
                    app.Image.ImageSource = "picture/emotion_3.png";
                case "fond"
                    app.Button.Text = "fond";
                    app.Image.ImageSource = "picture/emotion_3.png";
                case "kindness"
                    app.Button.Text = "kindness";
                    app.Image.ImageSource = "picture/emotion_4.png";
                case "kind"
                    app.Button.Text = "kind";
                    app.Image.ImageSource = "picture/emotion_4.png";
                case "compassionate"
                    app.Button.Text = "compassionate";
                    app.Image.ImageSource = "picture/emotion_4.png";
                case "affectionate"
                    app.Button.Text = "affectionate";
                    app.Image.ImageSource = "picture/emotion_4.png";
                    
                case "soothing"
                    app.Button.Text = "soothing";
                    app.Image.ImageSource = "picture/emotion_4.png";
                case "agitated"
                    app.Button.Text = "agitated";
                    app.Image.ImageSource = "picture/emotion_sad.png";
                case "tender"
                    app.Button.Text = "tender";
                    app.Image.ImageSource = "picture/emotion_4.png";
                case "serene"
                    app.Button.Text = "serene";
                    app.Image.ImageSource = "picture/emotion_4.png";
                case "strained"
                    app.Button.Text = "strained";
                    app.Image.ImageSource = "picture/emotion_sad.png";
                case "weary"
                    app.Button.Text = "weary";
                    app.Image.ImageSource = "picture/emotion_6.png";
                case "exhausted"
                    app.Button.Text = "exhausted";
                    app.Image.ImageSource = "picture/emotion_6.png";
                case "overwhelmed"
                    app.Button.Text = "overwhelmed";
                    app.Image.ImageSource = "picture/emotion_5.png";
                case "frustrated"
                    app.Button.Text = "frustrated";
                    app.Image.ImageSource = "picture/emotion_9.png";
            end


        end
        
        function emotion_analyse(app,text_output,word_list)
            app.get_emotion = [];
            hold(app.UIAxes_conversation,"off")
            cla(app.UIAxes_conversation)
            length_row = 35;    

            text_output = char(text_output);
            text_output = strrep(text_output, '\n', '');
            text_output = strrep(text_output, '\r', '');
            
            % Split text into sentences
            sentences = strsplit(text_output, '. ');

            app.row_num = 0;
            for ii = 1:length(sentences)
                
                sentence = char(sentences{ii});
                if isempty(sentence)
                    break
                else
                    disp_sentence(app,sentence,length_row,word_list)
                    [keyword_list,keyword_index]=extractKeywords(app,lower(sentence),word_list);
                    [key_start,key_order] = sort(keyword_index);

                    keyword_reallist = cell(1,length(key_order));
                    for i = 1:length(key_order)
                        keyword_reallist{key_order(i)} = keyword_list(2*i);
                    end

                    %Emotion perception
                    if ~isempty(keyword_reallist)
                        emotion_disp(app,keyword_reallist{1});
                        app.get_emotion = keyword_reallist{1};
                    end
                end


            end
 
                        
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.xData = -(app.bufflen-1)/app.data_f:1/app.data_f:0;

            app.h_Ori = animatedline(app.ori_vot, MaximumNumPoints=app.bufflen,Color=[197,90,17]./255);
            app.h_Ori2 = animatedline(app.ori_vot, MaximumNumPoints=app.bufflen,Color=[112,176,121]./255);
            app.h_Ori3 = animatedline(app.ori_vot, MaximumNumPoints=app.bufflen,Color='r');
            app.h_Ori4 = animatedline(app.ori_vot, MaximumNumPoints=app.bufflen,Color='b');
            app.h_Ori5 = animatedline(app.ori_vot, MaximumNumPoints=app.bufflen,Color=[0,0,0]);
            app.ori_vot.YLim=[-0.2,1];

            app.h_R1 = animatedline(app.catch_vot, MaximumNumPoints=1201,Color=[0,0,0]);
            app.catch_vot.XLim=[1,1200];
            app.catch_vot.YLim=[-0.4,0.8];
            
            addpath(".\picture")
            app.Image_cloud.ImageSource = "picture/cloud.png";
            app.Image_dog.ImageSource = "picture/dog.png";
            app.Image.ImageSource = "picture/emotion_ori.png";
            
%             blelist("Name","PetoiBLE-2500")
%             app.dog_ble = ble("567687172500");


        end

        % Button pushed function: stopButton
        function stopButtonPushed(app, event)
            stop(app.timer_id);
            delete(app.timer_id);
            closepy(app);
            app.dog_ble = [];
            app.dog_act = [];


        end

        % Callback function
        function startsaveButtonPushed(app, event)
            app.startsave = 1;
            app.endsave = 0;
            app.V1_all = [];
            app.V2_all = [];
            app.V3_all = [];
            app.V4_all = [];
            app.V5_all = [];
            app.V6_all = [];
            app.xdata_save = [];
        end

        % Callback function
        function endsaveButtonPushed(app, event)
            app.endsave = 1;
            data_V1 = app.V1_all;
            data_V2 = app.V2_all;
            data_V3 = app.V3_all;
            data_V4 = app.V4_all;
            data_V5 = app.V5_all;
            data_V6 = app.V6_all;
            data_x  = app.xdata_save;
            save("act_train/ori_data/temp_V1.mat","data_V1");
            save("act_train/ori_data/temp_V2.mat","data_V2");
            save("act_train/ori_data/temp_V3.mat","data_V3");
            save("act_train/ori_data/temp_V4.mat","data_V4");
            save("act_train/ori_data/temp_V5.mat","data_V5");
            save("act_train/ori_data/temp_V5.mat","data_V6");
            save("act_train/ori_data/temp_data_x.mat","data_x");
        end

        % Button pushed function: connectButton
        function linkdogButtonPushed(app, event)
            blelist("Name","PetoiBLE-2500")
            app.dog_ble = ble("567687172500");
            app.dog_act = characteristic(app.dog_ble,"FFE0","FFE1");

        end

        % Button pushed function: startButton
        function startButtonPushed(app, event)
            openAd7606(app);
            wxyy(app);
            app.net = importdata("\network_emotion_recognition\trainedGN.mat");
            app.timer_id = timer ('TimerFcn',{@app.adc_timer},'ExecutionMode','fixedDelay','Period',0.1);
            app.timer_id.BusyMode = 'queue';
            start(app.timer_id);
        end

        % Button pushed function: switch_state
        function switch_statePushed(app, event)
            if app.if_familiar == 0
                
                app.switch_state.Text = "This is a dog that is very familiar to you.";
                app.Image_emotion.ImageSource = "picture/dog_familiar.png";
                app.if_familiar = 1;
                app.ActionButton.Text = "And you...";
            else
                app.switch_state.Text = "It is the first time you meet the dog";
                
                app.Image_emotion.ImageSource = "picture/dog_justmet.png";
                
                app.if_familiar = 0;
                app.ActionButton.Text = "And you...";
            end
        end

        % Button pushed function: ActionButton
        function ActionButtonPushed(app, event)
            if app.if_morning == 0
                app.if_morning = 1;
                app.Image_emotion.ImageSource = "picture/leavehome_1.png";
                app.switch_state.Text = "Leave for work in the morning";
                app.ActionButton.Text = "And you...";
            elseif app.if_morning == 1
                app.if_morning = 2;
                app.Image_emotion.ImageSource = "picture/backhome_1.png";
                app.switch_state.Text = "Come home from work at night";
                app.ActionButton.Text = "And you...";
            elseif app.if_morning == 2
                app.if_morning = 0;
                app.Image_emotion.ImageSource = "picture/backhome_1.png";
                app.switch_state.Text = "Come home from work at night";
                app.ActionButton.Text = "And you...";
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.GridLayout3 = uigridlayout(app.Tab);
            app.Panel = uipanel(app.GridLayout3);
            app.Panel.Title = 'Panel';
            app.GridLayout2 = uigridlayout(app.Panel);
            app.GridLayout = uigridlayout(app.GridLayout2);
            app.UIAxes_conversation = uiaxes(app.GridLayout);
            app.Image_cloud = uiimage(app.GridLayout);
            app.Image_dog = uiimage(app.GridLayout);
            app.Image_emotion = uiimage(app.GridLayout);
            app.Image_emotion.ImageSource = '12.png';
            app.switch_state = uibutton(app.GridLayout, 'push');
            app.switch_state.ButtonPushedFcn = createCallbackFcn(app, @switch_statePushed, true);
            app.switch_state.Text = 'It is the first time you meet the dog';
            app.ActionButton = uibutton(app.GridLayout, 'push');
            app.ActionButton.ButtonPushedFcn = createCallbackFcn(app, @ActionButtonPushed, true);
            app.ActionButton.Text = 'You pat the dog by your warm hand and say goodbye';
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = 'emotion_happy.png';
            app.Button = uibutton(app.GridLayout, 'push');
            app.Button.Text = '';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.ori_vot = uiaxes(app.Tab2);
            title(app.ori_vot, 'Title')
            xlabel(app.ori_vot, 'X')
            ylabel(app.ori_vot, 'Y')
            zlabel(app.ori_vot, 'Z')
            app.catch_vot = uiaxes(app.Tab2);
            title(app.catch_vot, 'Title')
            xlabel(app.catch_vot, 'X')
            ylabel(app.catch_vot, 'Y')
            zlabel(app.catch_vot, 'Z')
            app.startButton = uibutton(app.Tab2, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Text = 'start';
            app.stopButton = uibutton(app.Tab2, 'push');
            app.stopButton.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.stopButton.Text = 'stop';
            app.connectButton = uibutton(app.Tab2, 'push');
            app.connectButton.ButtonPushedFcn = createCallbackFcn(app, @linkdogButtonPushed, true);
            app.connectButton.Text = 'connect';
            app.actdog = uibutton(app.Tab2, 'push');
            app.actdog.Text = 'Button2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dog_affective_interaction

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end