classdef robohand < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Panel           matlab.ui.container.Panel
        GridLayout2     matlab.ui.container.GridLayout
        GridLayout      matlab.ui.container.GridLayout
        Button2         matlab.ui.control.Button
        Button          matlab.ui.control.Button
        Image_xyz       matlab.ui.control.Image
        Image_logo      matlab.ui.control.Image
        Image_slide_2   matlab.ui.control.Image
        Image_slide_1   matlab.ui.control.Image
        Image33         matlab.ui.control.Image
        Image32         matlab.ui.control.Image
        Image31         matlab.ui.control.Image
        Image23         matlab.ui.control.Image
        Image22         matlab.ui.control.Image
        Image21         matlab.ui.control.Image
        Image13         matlab.ui.control.Image
        Image12         matlab.ui.control.Image
        Image11         matlab.ui.control.Image
        connectButton   matlab.ui.control.Button
        stopButton      matlab.ui.control.Button
        startButton     matlab.ui.control.Button
        UIAxesline      matlab.ui.control.UIAxes
        UIAxesrealvolt  matlab.ui.control.UIAxes
        UIAxes1         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        % Description
        ifUsepy=1;
        byIndex;
        real_vot_1=[];  real_vot_2=[];  real_vot_3=[];  real_vot_4=[];
        ori_vot_1 = []; ori_vot_2 = []; ori_vot_3 = []; ori_vot_4 = [];
        V1_all = [];V2_all=[];V3_all=[];V4_all=[];xdata_save=[];
        V1_acttest;V2_acttest;V3_acttest;V4_acttest;
        V1;V2;V3;V4;data_total;
        vot_integ_1 = 0;
        vot_integ_2 = 0;
        vot_integ_3 = 0;
        vot_integ_4 = 0;
        vot_integ_sum;
        timer_id;
        runtime = 0;
        lastruntime=0;
        xData = [];
        data_f = 1000;
        bufflen=10000;
        data_len=0;
        data_len_total=0;
        mean_num = 2000;
        Method = 3;
        zf1; zf2; zf3; zf4;
        zf11; zf21; zf31; zf41;
        zf12; zf22; zf32; zf42;

        M;
        
        x; y; X; Y;
        XX;YY;Zi=zeros(52);
        show_x;show_y;
        F_data;

        Mat1 = [];
        Mat2 = [];
        w1;w2;mu;
        countOfCsv = 1;
        ydata;
        last_posi = 0;
        posi=0;
        csvlen=100;
        word_x;word_y;
        last_wordx;last_wordy;
        Ratio1;Ratio2;
        last_ratio1;last_ratio2;
        temp_x;temp_y;
        OutYall=[];
        noiseall=[];
        mean_i = 0*ones(8,1);
        lastpeak = 0;
        zStart = -1;
        zEnd = -1;
        useContour = 1;
        fitresult;fitresult2;
        h_Ori; h_Ori2;h_Ori3; h_Ori4;
        h_Noise; h_Noise2;
        h_Integ;
        h_Integ1;h_Integ2;
        h_R1;h_R2;
        integ_eff = 0;
        thr = 0.05;
        min_thr = -0.04;
        tcmode = 1;
        slidemode = 0;
        integ_all;
        startsave=0;
        endsave = 0;
        thr_net = 0.003;
        YPred=[];

        nSocket;
        joint1;
        hand_con;
        still_slide = 0;
        beginmove=0;
        timer_id_1;
        wordx_save;
        wordy_save;
        turn_dir = 0;
        if_grab = 0;
        data_V1_save;

        now_x=0;now_y = 0;now_z = 0;
        last_x = 0;last_y = 0;last_z = 0;
        plot_x;plot_y;plot_z;
        movespeed_xyz = 0;
        move_type = 0;

        integ_all_save;
    end
    
    methods (Access = public)
        %Timer of adc
        function adc_timer(app,~,~)
            %read data from adc
            readAd7606(app);
            %record the runtime
            app.runtime = app.runtime+1;
            if app.runtime-app.lastruntime>=1
                app.beginmove = 0;
            end
            %record the position of robot hand
            app.last_x = app.now_x;
            app.last_y = app.now_y;
            app.last_z = app.now_z;
            if app.move_type == 1
                app.now_z = app.last_z-app.movespeed_xyz*0.1;
            elseif app.move_type == 2
                app.now_z = app.last_z+app.movespeed_xyz*0.1;
            elseif app.move_type == 3
                app.now_y = app.last_y-app.movespeed_xyz*0.1;
            elseif app.move_type == 4
                app.now_y = app.last_y+app.movespeed_xyz*0.1;
            elseif app.move_type == 5
                app.now_x = app.last_x-app.movespeed_xyz*0.1;
            elseif app.move_type == 6
                app.now_x = app.last_x+app.movespeed_xyz*0.1;

            end

            %get valid data
            if app.data_len > 0 
                try
                    xdata = app.xData(app.bufflen-app.data_len+1:app.bufflen);
                    % Linear fitting
                    degree = 1;  % Order of linear fit
                    coefficients_x = polyfit([xdata(1),xdata(end)], [app.last_x,app.now_x], degree);
                    coefficients_y = polyfit([xdata(1),xdata(end)], [app.last_y,app.now_y], degree);
                    coefficients_z = polyfit([xdata(1),xdata(end)], [app.last_z,app.now_z], degree);
                    
                    fit_x = polyval(coefficients_x, xdata);
                    fit_y = polyval(coefficients_y, xdata);
                    fit_z = polyval(coefficients_z, xdata);
                    addpoints(app.plot_x,xdata,fit_x);
                    addpoints(app.plot_y,xdata,fit_y);
                    addpoints(app.plot_z,xdata,fit_z);


                    %output row data
                    addpoints(app.h_Ori,xdata,app.V1+0.15);
                    addpoints(app.h_Ori2,xdata,app.V2+0.05);
                    addpoints(app.h_Ori3,xdata,app.V3-0.05);
                    addpoints(app.h_Ori4,xdata,app.V4-0.15);
                    %Sum of integrals
                    app.vot_integ_sum = app.vot_integ_1+app.vot_integ_2+app.vot_integ_3+app.vot_integ_4;
%                     addpoints(app.h_Integ,xdata,app.vot_integ_sum);
                    %sun of data
                    app.data_total = app.V1+app.V2+app.V3+app.V4;
                    %use to save data
                    if app.startsave && ~app.endsave
                        app.V1_all = [app.V1_all,app.V1];
                        app.V2_all = [app.V2_all,app.V2];
                        app.V3_all = [app.V3_all,app.V3];
                        app.V4_all = [app.V4_all,app.V4];
                        app.integ_all_save  = [app.integ_all_save,app.vot_integ_sum'];
                        app.xdata_save = [app.xdata_save,xdata];
                    end
                    %find peaks
                    [peaks_data_1,peaks_index_1] = findpeaks(app.V1);
                    [peaks_data_2,peaks_index_2] = findpeaks(app.V2);
                    [peaks_data_3,peaks_index_3] = findpeaks(app.V3);
                    [peaks_data_4,peaks_index_4] = findpeaks(app.V4);
                    [peaks_data_total,peaks_index_total] = findpeaks(app.data_total);
                    
    %                 thr_index = find(abs(peaks_data_total)>thr);
    %                 peaks_right = peak_find(app,thr_index,peaks_data_total(thr_index),50);
    %                 peaks_index_right = peaks_index_total;
                    %find valid peaks
                    peaks_right = find(peaks_data_total>app.thr);
                    peaks_index_right = peaks_index_total;
                    peaks_1 = right_peak(app,peaks_right,peaks_index_right,peaks_index_1,peaks_data_1,0);
                    peaks_2 = right_peak(app,peaks_right,peaks_index_right,peaks_index_2,peaks_data_2,0);
                    peaks_3 = right_peak(app,peaks_right,peaks_index_right,peaks_index_3,peaks_data_3,0);
                    peaks_4 = right_peak(app,peaks_right,peaks_index_right,peaks_index_4,peaks_data_4,0);
                   
                    DVM1 = peaks_data_1(peaks_1);
                    DVM2 = peaks_data_2(peaks_2);
                    DVM3 = peaks_data_3(peaks_3);
                    DVM4 = peaks_data_4(peaks_4);

                    app.Ratio1 = zeros(1,length(DVM1));
                    app.Ratio2 = zeros(1,length(DVM1));
   
                catch MException
                    disp(MException);
                    disp('峰值检测错误')
                end

                try
                    for i = 1:length(DVM1)
                        %get ratios
                        app.Ratio1(i) = (DVM1(i)+DVM2(i))./(DVM1(i)+DVM2(i)+DVM3(i)+DVM4(i));
                        app.Ratio2(i) = (DVM1(i)+DVM4(i))./(DVM1(i)+DVM2(i)+DVM3(i)+DVM4(i));
%                         Ratio1(i) = (1/DVM1(j)+1/DVM2(j))/(1/DVM1(j)+1/DVM2(j)+1/DVM3(j)+1/DVM4(j));
%                         Ratio2(i) = (1/DVM1(j)+1/DVM4(j))/(1/DVM1(j)+1/DVM2(j)+1/DVM3(j)+1/DVM4(j));
                    end
                    
                    R_1 = find(app.Ratio1~=0);
%                     
                      % plot ratios
%                     addpoints(app.h_R1,xdata(R_1),app.Ratio1(R_1));
%                     addpoints(app.h_R2,xdata(R_1),app.Ratio2(R_1));
                    

                    %remove 0
                    app.Ratio1 = app.Ratio1(R_1);
                    app.Ratio2 = app.Ratio2(R_1);
                    %Contact mode stop
                    if ~isempty(app.data_total)
                        if min(app.data_total)<app.min_thr && app.slidemode == 0
                            disp("stoppointmove")
                            reset_pic(app)
                            app.move_type = 0;
                            app.hand_con.stop_move_xyz(app.nSocket)
                            
                        end
                    end
                    %slide mode stop
                    if ~isempty(app.data_total) && app.slidemode == 1
                        if min(app.data_total)<=app.min_thr 
                            disp("stopslide")
                            reset_pic(app)
                            app.word_x = [];
                            app.word_y = [];
                            app.last_wordx = [];
                            app.last_wordy = [];
                            app.last_ratio1 = [];
                            app.last_ratio2 = [];
                            app.still_slide = 0;
                            app.wordx_save = [];
                            app.wordy_save = [];
                            app.slidemode = 0;
                            app.turn_dir = 0;
                            app.thr = 0.03;
                            app.min_thr = -0.06;
                            app.hand_con.stop_move_xyz(app.nSocket)
                            app.move_type = 0;
                        end
                    end
                    
%                     addpoints(app.h_Integ,xdata,app.vot_integ_sum);
%                     if ~isempty(app.Ratio1)                  
%                         addpoints(app.h_Integ,xdata,app.vot_integ_sum);
%                     else
%                         addpoints(app.h_Integ,xdata,zeros(1,length(app.vot_integ_sum)));
%                     end

                catch MException
                    disp(MException);
                    disp('比值计算错误')
                end

%             else
%                 app.Ratio1 = [];
%                 app.Ratio2 = [];
%                 app.word_x = [];
%                 app.word_y = [];
            end
                 
        
        end
    end
    
    methods (Access = private)
        function openAd7606(app)
            %open adc
            py.importlib.import_module('ad7606_read');
            obj = py.importlib.import_module('ad7606_read');
            py.importlib.reload(obj);
            P = py.sys.path; 
            if count(P,'./ad7606_read.py') == 0
               insert(P,int32(0),'./ad7606_read.py');
            end
            py.importlib.import_module('ad7606_read');
            delete(timerfind);
            app.byIndex = py.ad7606_read.openadc(int32(1000000/app.data_f));
            py.ad7606_read.adc_startread(app.byIndex);
        end

        function readAd7606(app)
            %get data from adc
            real_vot = double(py.ad7606_read.readadc_data(app.byIndex))';       
            data_len_r = length(real_vot);
            app.data_len = data_len_r / 8;
            if app.data_len > 0
                temp_vot_1 = zeros(app.data_len,1);
                temp_vot_2 = zeros(app.data_len,1);
                temp_vot_3 = zeros(app.data_len,1);
                temp_vot_4 = zeros(app.data_len,1);
                for i = 1:app.data_len
                    temp_vot_1(i) = real_vot(i*8-7);
                    temp_vot_2(i) = real_vot(i*8-6);
                    temp_vot_3(i) = real_vot(i*8-5);
                    temp_vot_4(i) = real_vot(i*8-4);
                end
%                 if app.runtime == 100
%                     app.data_V1_save = [];
%                 elseif app.runtime>100
%                     app.data_V1_save = [app.data_V1_save;temp_vot_1];
%                 end
%                 if app.runtime==600
%                     data_V1 = app.data_V1_save;
%                     save("V1.mat","data_V1");
%                 end

                %filter
                [~,b]=lp_20_50(app.data_f);
                [~,b1] = lp_30_70(app.data_f);

% %                 Fstop_norm = 100/app.data_f; % 归一化截止频率
%                 Fpass_norm = 200/app.data_f; % 归一化通带频率
%                 
%                 % 使用fir1函数设计FIR滤波器
%                 order_N_hp = 30; % 滤波器阶数
%                 hb = fir1(order_N_hp, Fpass_norm, 'high', hamming(order_N_hp+1));
% 
%                 Wn = [250/(app.data_f/2), 450/(app.data_f/2)]; % 归一化的带阻频率范围
% 
%                 % 使用fir1函数设计FIR滤波器
%                 order_N_bs = 30; % 滤波器阶数
%                 bb = fir1(order_N_bs, Wn, 'stop', hamming(order_N_bs+1));

                if isempty(app.zf1)
                    [temp_vot_11,app.zf1] = filter(b,1,temp_vot_1);
                    [temp_vot_21,app.zf2] = filter(b,1,temp_vot_2);
                    [temp_vot_31,app.zf3] = filter(b,1,temp_vot_3);
                    [temp_vot_41,app.zf4] = filter(b,1,temp_vot_4);
                    [temp_vot_slide_11,app.zf11] = filter(b1,1,temp_vot_1);
                    [temp_vot_slide_21,app.zf21] = filter(b1,1,temp_vot_2);
                    [temp_vot_slide_31,app.zf31] = filter(b1,1,temp_vot_3);
                    [temp_vot_slide_41,app.zf41] = filter(b1,1,temp_vot_4);
%                     [temp_vot_hp_11,app.zf11] = filter(hb,1,temp_vot_1);
%                     [temp_vot_hp_21,app.zf21] = filter(hb,1,temp_vot_2);
%                     [temp_vot_hp_31,app.zf31] = filter(hb,1,temp_vot_3);
%                     [temp_vot_hp_41,app.zf41] = filter(hb,1,temp_vot_4);
%                     [temp_vot_bs_11,app.zf12] = filter(bb,1,temp_vot_hp_11);
%                     [temp_vot_bs_21,app.zf22] = filter(bb,1,temp_vot_hp_21);
%                     [temp_vot_bs_31,app.zf32] = filter(bb,1,temp_vot_hp_31);
%                     [temp_vot_bs_41,app.zf42] = filter(bb,1,temp_vot_hp_41);
                else 
                    [temp_vot_11,app.zf1] = filter(b,1,temp_vot_1,app.zf1);
                    [temp_vot_21,app.zf2] = filter(b,1,temp_vot_2,app.zf2);
                    [temp_vot_31,app.zf3] = filter(b,1,temp_vot_3,app.zf3);
                    [temp_vot_41,app.zf4] = filter(b,1,temp_vot_4,app.zf4);
                    [temp_vot_slide_11,app.zf11] = filter(b1,1,temp_vot_1,app.zf11);
                    [temp_vot_slide_21,app.zf21] = filter(b1,1,temp_vot_2,app.zf21);
                    [temp_vot_slide_31,app.zf31] = filter(b1,1,temp_vot_3,app.zf31);
                    [temp_vot_slide_41,app.zf41] = filter(b1,1,temp_vot_4,app.zf41);

%                     [temp_vot_hp_11,app.zf11] = filter(hb,1,temp_vot_1,app.zf11);
%                     [temp_vot_hp_21,app.zf21] = filter(hb,1,temp_vot_2,app.zf21);
%                     [temp_vot_hp_31,app.zf31] = filter(hb,1,temp_vot_3,app.zf31);
%                     [temp_vot_hp_41,app.zf41] = filter(hb,1,temp_vot_4,app.zf41);
%                     [temp_vot_bs_11,app.zf12] = filter(bb,1,temp_vot_hp_11,app.zf12);
%                     [temp_vot_bs_21,app.zf22] = filter(bb,1,temp_vot_hp_21,app.zf22);
%                     [temp_vot_bs_31,app.zf32] = filter(bb,1,temp_vot_hp_31,app.zf32);
%                     [temp_vot_bs_41,app.zf42] = filter(bb,1,temp_vot_hp_41,app.zf42);
                end
    
                %Abscissa assignment
                app.xData = app.xData + app.data_len / app.data_f;
                % Fill new array
                app.real_vot_1 = temp_vot_11;
                app.real_vot_2 = temp_vot_21;
                app.real_vot_3 = temp_vot_31;
                app.real_vot_4 = temp_vot_41;
%                 app.real_vot_1 = temp_vot_11;
%                 app.real_vot_2 = temp_vot_21;
%                 app.real_vot_3 = temp_vot_31;
%                 app.real_vot_4 = temp_vot_41;
                if app.integ_eff ~= 0
                    app.vot_integ_1(end) = 0;
                    app.vot_integ_2(end) = 0;
                    app.vot_integ_3(end) = 0;
                    app.vot_integ_4(end) = 0;
                end
%                Integral array update
                if length(app.vot_integ_1) < 2
                    app.vot_integ_1 = ones(app.data_len,1);
                    app.vot_integ_2 = ones(app.data_len,1);
                    app.vot_integ_3 = ones(app.data_len,1);
                    app.vot_integ_4 = ones(app.data_len,1);
                end
                temp_integ_1 = app.vot_integ_1;
                temp_integ_2 = app.vot_integ_2;
                temp_integ_3 = app.vot_integ_3;
                temp_integ_4 = app.vot_integ_4;
                %integral data
                app.vot_integ_1 = ones(app.data_len,1)*app.vot_integ_1(end);
                app.vot_integ_2 = ones(app.data_len,1)*app.vot_integ_2(end);
                app.vot_integ_3 = ones(app.data_len,1)*app.vot_integ_3(end);
                app.vot_integ_4 = ones(app.data_len,1)*app.vot_integ_4(end);
                
                temp_len = length(temp_integ_1);
                integ_thr_1 = 4;
                integ_thr_2 = 2;
                integ_time = 0.02*app.data_f;
   
                app.V1 = [];
                app.V2 = [];
                app.V3 = [];
                app.V4 = [];
                %calculate mean of row data
                for i = 1:app.data_len
                    app.mean_i(1) = app.mean_i(1)+(app.real_vot_1(i)-app.mean_i(1)) / 10000 * app.integ_eff;
                    app.mean_i(2) = app.mean_i(2)+(app.real_vot_2(i)-app.mean_i(2)) / 10000 * app.integ_eff;
                    app.mean_i(3) = app.mean_i(3)+(app.real_vot_3(i)-app.mean_i(3)) / 10000 * app.integ_eff;
                    app.mean_i(4) = app.mean_i(4)+(app.real_vot_4(i)-app.mean_i(4)) / 10000 * app.integ_eff;
                    app.mean_i(5) = app.mean_i(5)+(temp_vot_slide_11(i)-app.mean_i(5)) / 10000 * app.integ_eff;
                    app.mean_i(6) = app.mean_i(6)+(temp_vot_slide_21(i)-app.mean_i(6)) / 10000 * app.integ_eff;
                    app.mean_i(7) = app.mean_i(7)+(temp_vot_slide_31(i)-app.mean_i(7)) / 10000 * app.integ_eff;
                    app.mean_i(8) = app.mean_i(8)+(temp_vot_slide_41(i)-app.mean_i(8)) / 10000 * app.integ_eff;
                    if app.real_vot_1(i)-app.mean_i(1) < 0.01 && app.real_vot_1(i)-app.mean_i(1)>-0.01
                        app.vot_integ_1(i:end) = app.vot_integ_1(i:end);
                        app.vot_integ_2(i:end) = app.vot_integ_2(i:end);
                        app.vot_integ_3(i:end) = app.vot_integ_3(i:end);
                        app.vot_integ_4(i:end) = app.vot_integ_4(i:end); 
                    else
                        app.vot_integ_1(i:end) = app.vot_integ_1(i:end)+app.real_vot_1(i)-app.mean_i(1);
                        app.vot_integ_2(i:end) = app.vot_integ_2(i:end)+app.real_vot_2(i)-app.mean_i(2);
                        app.vot_integ_3(i:end) = app.vot_integ_3(i:end)+app.real_vot_3(i)-app.mean_i(3);
                        app.vot_integ_4(i:end) = app.vot_integ_4(i:end)+app.real_vot_4(i)-app.mean_i(4);
                    end
                    app.integ_all(i) = app.vot_integ_1(i)+app.vot_integ_2(i)+app.vot_integ_3(i)+app.vot_integ_4(i);
                    if i < integ_time
                        if app.vot_integ_1(i)+app.vot_integ_2(i)+app.vot_integ_3(i)+app.vot_integ_4(i)...
                                -temp_integ_1(temp_len-integ_time+1)-temp_integ_2(temp_len-integ_time+1)-temp_integ_3(temp_len-integ_time+1)-temp_integ_4(temp_len-integ_time+1)>integ_thr_1
                            app.integ_eff = 0;
                        end
                        if app.integ_eff == 0 && app.vot_integ_1(i)+app.vot_integ_2(i)+app.vot_integ_3(i)+app.vot_integ_4(i)...
                                -temp_integ_1(temp_len-integ_time+1)-temp_integ_2(temp_len-integ_time+1)-temp_integ_3(temp_len-integ_time+1)-temp_integ_4(temp_len-integ_time+1)<-integ_thr_2
                            app.integ_eff = 5;
                        end                    
                    else
                        if app.vot_integ_1(i)+app.vot_integ_2(i)+app.vot_integ_3(i)+app.vot_integ_4(i)...
                            -(app.vot_integ_1(i-integ_time+1)+app.vot_integ_2(i-integ_time+1)+app.vot_integ_3(i-integ_time+1)+app.vot_integ_4(i-integ_time+1))>integ_thr_1
                        app.integ_eff = 0;
                        end
                        if app.integ_eff == 0 && app.vot_integ_1(i)+app.vot_integ_2(i)+app.vot_integ_3(i)+app.vot_integ_4(i)...
                                -(app.vot_integ_1(i-integ_time+1)+app.vot_integ_2(i-integ_time+1)+app.vot_integ_3(i-integ_time+1)+app.vot_integ_4(i-integ_time+1))<-integ_thr_2
                            app.integ_eff = 5;
                        end
                        
                    end
                    if app.vot_integ_1(i)+app.vot_integ_2(i)+app.vot_integ_3(i)+app.vot_integ_4(i)<0
                        app.vot_integ_1(i) = 0;
                        app.vot_integ_2(i) = 0;
                        app.vot_integ_3(i) = 0;
                        app.vot_integ_4(i) = 0;
                    end
                    %valid data
                    app.V1(i) = app.real_vot_1(i)-app.mean_i(1);
                    app.V2(i) = app.real_vot_2(i)-app.mean_i(2);
                    app.V3(i) = app.real_vot_3(i)-app.mean_i(3);
                    app.V4(i) = app.real_vot_4(i)-app.mean_i(4);

                end
                app.countOfCsv = app.countOfCsv+1;
                app.data_len_total = app.data_len_total + app.data_len;
                if app.countOfCsv == 15
                    app.mean_i(1) = (app.mean_i(1) + app.vot_integ_1(end)) / app.data_len_total;
                    app.mean_i(2) = (app.mean_i(2) + app.vot_integ_2(end)) / app.data_len_total;
                    app.mean_i(3) = (app.mean_i(3) + app.vot_integ_3(end)) / app.data_len_total;
                    app.mean_i(4) = (app.mean_i(4) + app.vot_integ_4(end)) / app.data_len_total;
                    app.vot_integ_1(end) = 0;
                    app.vot_integ_2(end) = 0;
                    app.vot_integ_3(end) = 0;
                    app.vot_integ_4(end) = 0;
                    app.integ_eff = 5;
                end

            end
        end
        
        function closepy(app)
            %close adc
            py.ad7606_read.close_device(app.byIndex);

        end


        function peaks_num = right_peak(app,peaks_right,peaks_index_right,peaks_index_wrong,peaks_data_wrong,scope_num)
            peaks_num = [];
            for k = 1:length(peaks_right)

                right_num = peaks_index_right(peaks_right(k));
                
                sub_index = abs(peaks_index_wrong-right_num);
                [~,min_num] = min(sub_index);
                if min_num-scope_num>0
                    start_peak = min_num-scope_num;
                else
                    start_peak = 1;
                end
                if min_num+scope_num<=length(peaks_data_wrong)
                    end_peak = min_num+scope_num;
                else
                    end_peak = length(peaks_data_wrong);
                end
                
                if min_num+scope_num>length(peaks_data_wrong)   

                    [~,max_num] = max(peaks_data_wrong(start_peak:end));
                    peaks_num(k) = min_num;
                elseif min_num-scope_num<1
                    [~,max_num] = max(peaks_data_wrong(1:end_peak));
                    peaks_num(k) = min_num;
                else
                    [~,max_num] = max(peaks_data_wrong(start_peak:end_peak));
                    peaks_num(k) = max_num+min_num-scope_num-1;
                end
                             
            end
%             if isempty(peaks_num)
%                 return
%             end
        
        end
        
        function peaks = peak_find(app,peaks_index,peaks_data,sco_num)
            last_peak = 0;
            peak_num = 1;
            peaks = [];
            for j = 1:length(peaks_index)
                if last_peak ==0
                    last_peak = j;
                elseif peaks_index(j)-peaks_index(last_peak)>sco_num
                    peaks(peak_num) = peaks_index(last_peak);
                    peak_num = peak_num+1;
                    last_peak = j;
                else
                    if peaks_data(j)>peaks_data(last_peak)
                        last_peak = j;
        
                    end
                           
                end
          
            end
            if isempty(peaks)
                return
            end

        end


        function handcatch(app)
            app.hand_con.hand_catch(app.nSocket)
        end
  
        function setangle(app,angle_num,angle_movespeed,angle_movestep)
            app.hand_con.move_add_joint(app.nSocket,angle_num,angle_movespeed,angle_movestep);
        end
        
        function [x,y] = locword(~,r1,r2)
            Mat1_mid = importdata('eqlinedata/12_1234_mid.mat');
            Mat2_mid = importdata('eqlinedata/14_1234_mid.mat');
            M_len=length(Mat1_mid);
            x = linspace(1,M_len,M_len);
            y = linspace(1,M_len,M_len);
            [mat_x, mat_y] = meshgrid(x,y);
            [c1,~]=contour(mat_x,mat_y,Mat1_mid,[r1,r2]);
            [c2,~]=contour(mat_x,mat_y,Mat2_mid,[r1,r2]);
            p = intp2(c1,c2);
            if ~isempty(p) %If the intersection exists
                x = p(1,1);
                y = p(1,2);
            else %If the intersection doesn't exist
                x = 0;
                y = 0;
            end

        end
        
        
        function point_move(app)
            seg_1 = 0;
            seg_2 = app.M/4;
            seg_3 = 2*app.M/4;
            seg_4 = 3*app.M/4;
            seg_5 = app.M;
            speed1=5;
            speed2=15;
            %select speed
            if max(app.data_total)*70>15
                move_speed = speed2;
            else
                move_speed = speed1;
            end
            thr_slide = 1;
            thr_slide_1 = -0.07;
            
            %Different coordinates represent different instructions
            if app.Ratio1(end) ~= app.last_ratio1 && app.beginmove == 0 
                app.beginmove = 1;
                reset_pic(app);
                if app.word_x(end)<=seg_2 && app.word_x(end)>seg_1 && app.word_y(end)<=seg_3 && app.word_y(end)>seg_2
                    disp('movez-')
                    app.Image21.ImageSource = 'picture/z-_point.png';
                    app.lastruntime = app.runtime;
                    app.hand_con.move_xyz(app.nSocket,int32(2),int32(0),int32(move_speed),int32(0)); 
                    app.move_type = 1;
                    app.movespeed_xyz = move_speed;
                    
                end
                if app.word_x(end)<=seg_2 && app.word_x(end)>seg_1 && app.word_y(end)<=seg_4 && app.word_y(end)>seg_3
                    disp('movez+')
                    app.Image31.ImageSource = 'picture/z+_point.png';
                    app.lastruntime = app.runtime;
                    app.hand_con.move_xyz(app.nSocket,int32(2),int32(1),int32(move_speed),int32(0)) 
                    app.move_type = 2;
                    app.movespeed_xyz = move_speed;
                    
                end
    
                if app.word_x(end)<=seg_3 && app.word_x(end)>seg_2 && app.word_y(end)<=seg_3 && app.word_y(end)>seg_2
                    disp('movey-') 
                    app.Image22.ImageSource = 'picture/y-_point.png';
                    app.lastruntime = app.runtime;
                    app.hand_con.move_xyz(app.nSocket,int32(1),int32(0),int32(move_speed),int32(0))  
                    app.move_type = 3;
                    app.movespeed_xyz = move_speed;
                end
                if app.word_x(end)<=seg_3 && app.word_x(end)>seg_2 && app.word_y(end)<=seg_4 && app.word_y(end)>seg_3
                    disp('movey+')  
                    app.Image32.ImageSource = 'picture/y+_point.png';
                    app.lastruntime = app.runtime;
                    app.hand_con.move_xyz(app.nSocket,int32(1),int32(1),int32(move_speed),int32(0))   
                    app.move_type = 4;
                    app.movespeed_xyz = move_speed;
                end
    
                if app.word_x(end)<=seg_4 && app.word_x(end)>seg_3 && app.word_y(end)<=seg_3 && app.word_y(end)>seg_2
                    disp('movex-')  
                    app.Image23.ImageSource = 'picture/x-_point.png';
                    app.lastruntime = app.runtime;
                    app.hand_con.move_xyz(app.nSocket,int32(0),int32(0),int32(move_speed),int32(0))
                    app.move_type = 5;
                    app.movespeed_xyz = move_speed;
                end
                if app.word_x(end)<=seg_4 && app.word_x(end)>seg_3 && app.word_y(end)<=seg_4 && app.word_y(end)>seg_3
                    disp('movex+') 
                    app.Image33.ImageSource = 'picture/x+_point.png';
                    app.lastruntime = app.runtime;
                    app.hand_con.move_xyz(app.nSocket,int32(0),int32(1),int32(move_speed),int32(0))
                    app.move_type = 6;
                    app.movespeed_xyz = move_speed;
                end
    
                if app.word_x(end)<=seg_4 && app.word_x(end)>seg_3 && app.word_y(end)<=seg_2 && app.word_y(end)>seg_1
                    if app.if_grab == 0
                        disp("grab")
                        app.Image13.ImageSource = 'picture/grab_point.png';
                        app.lastruntime = app.runtime;
                        app.hand_con.hand_angle(app.nSocket,int32(1000),int32(1000),int32(10),int32(10),int32(200),int32(1000))  
                        app.if_grab = 1;
                    elseif app.if_grab == 1
                        disp("release")
                        app.Image13.ImageSource = 'picture/release_point.png';
                        app.lastruntime = app.runtime;
                        app.hand_con.hand_angle(app.nSocket,int32(1000),int32(1000),int32(1000),int32(1000),int32(800),int32(1000))  
                        app.if_grab = 0;
                    end
                    app.move_type = 0;
                    
                end
                
             
                if app.word_x(end)<=seg_2 && app.word_x(end)>seg_1 && app.word_y(end)<=seg_2 && app.word_y(end)>seg_1
                    disp("clockwise")
                    app.lastruntime = app.runtime;
                    app.Image11.ImageSource = 'picture/clockwise_point.png';
                    app.hand_con.move_joint(app.nSocket,int32(6),int32(1),int32(5))   
                    app.move_type = 0;
                    
                end
                if app.word_x(end)<=seg_3 && app.word_x(end)>seg_2 && app.word_y(end)<=seg_2 && app.word_y(end)>seg_1
                    disp("anticlockwise")
                    app.lastruntime = app.runtime;
                    app.Image12.ImageSource = 'picture/anticlockwise_point.png';
                    app.hand_con.move_joint(app.nSocket,int32(6),int32(0),int32(5))   
                    app.move_type = 0;
                end


                if app.word_x(end)<=seg_5 && app.word_x(end)>seg_4 && app.word_y(end)<=seg_5 && app.word_y(end)>seg_4
                    disp("beginslide")
                    app.lastruntime = app.runtime;
                    app.slidemode = 1;
                    app.thr = thr_slide;
                    app.min_thr = thr_slide_1;
                    app.wordx_save = 4;
                    app.wordy_save = 4;
                    app.still_slide = 0;
                    app.move_type = 0;
                end
                if app.word_x(end)<=seg_4 && app.word_x(end)>seg_3 && app.word_y(end)<=seg_5 && app.word_y(end)>seg_4
                    disp("beginslide")
                    app.lastruntime = app.runtime;
                    app.slidemode = 1;
                    app.thr = thr_slide;
                    app.min_thr = thr_slide_1;
                    app.wordx_save = 3;
                    app.wordy_save = 4;
                    app.still_slide = 0;
                    app.move_type = 0;
                end
                if app.word_x(end)<=seg_5 && app.word_x(end)>seg_4 && app.word_y(end)<=seg_2 && app.word_y(end)>seg_1
                    disp("beginslide")  
                    app.lastruntime = app.runtime;
                    app.slidemode = 1;
                    app.thr = thr_slide;
                    app.min_thr = thr_slide_1;
                    app.wordx_save = 4;
                    app.wordy_save = 1;
                    app.still_slide = 0;
                    app.move_type = 0;
                end
                if app.word_x(end)<=seg_2 && app.word_x(end)>seg_1 && app.word_y(end)<=seg_5 && app.word_y(end)>seg_4
                    disp("beginslide")  
                    app.lastruntime = app.runtime;
                    app.slidemode = 1;
                    app.thr = thr_slide;
                    app.min_thr = thr_slide_1;
                    app.wordx_save = 1;
                    app.wordy_save = 4;
                    app.still_slide = 0;
                    app.move_type = 0;
                end
%                 if app.word_x(end)<=seg_5 && app.word_x(end)>seg_4 && app.word_y(end)<=seg_5 && app.word_y(end)>seg_4
%                     disp("movedown")
%                     app.lastruntime = app.runtime;
%                     app.Image_slide_2.ImageSource = 'picture/move_down.png';
%                     app.hand_con.move_xyz(app.nSocket,int32(2),int32(0),int32(speed3),int32(0)); 
%                     app.move_type = 1;
%                     app.movespeed_xyz = speed3;
%                 end
%                 if app.word_x(end)<=seg_4 && app.word_x(end)>seg_3 && app.word_y(end)<=seg_5 && app.word_y(end)>seg_4
%                     disp("moveleft")
%                     app.lastruntime = app.runtime;
%                     app.Image_slide_1.ImageSource = 'picture/move_left.png';
%                     app.hand_con.move_xyz(app.nSocket,int32(0),int32(1),int32(speed3),int32(0)); 
%                     app.move_type = 6;
%                     app.movespeed_xyz = speed3;
%                 end
%                 if app.word_x(end)<=seg_5 && app.word_x(end)>seg_4 && app.word_y(end)<=seg_2 && app.word_y(end)>seg_1
%                     disp("moveup")
%                     app.lastruntime = app.runtime;
%                     app.Image_slide_2.ImageSource = 'picture/move_up.png';
%                     app.hand_con.move_xyz(app.nSocket,int32(2),int32(1),int32(speed3),int32(0)); 
%                     app.move_type = 2;
%                     app.movespeed_xyz = speed3;
%                 end
%                 if app.word_x(end)<=seg_2 && app.word_x(end)>seg_1 && app.word_y(end)<=seg_5 && app.word_y(end)>seg_4
%                     disp("moveright")
%                     app.lastruntime = app.runtime;
%                     app.Image_slide_1.ImageSource = 'picture/move_right.png';
%                     app.hand_con.move_xyz(app.nSocket,int32(0),int32(0),int32(speed3),int32(0)); 
%                     app.move_type = 5;
%                     app.movespeed_xyz = speed3;
%                 end
%                 
            end


        end
        
        function slide_move(app)
            %slide mode
            %different track represent different instrument
            if ~isempty(app.word_x)
                move_speed = 2;  

                if app.wordx_save(1) == 3 && app.wordy_save(1) == 4 && app.still_slide ~= 1      
                    disp("moveleft")
                    pause(0.5)
                    reset_pic(app)
                    app.Image_slide_1.ImageSource = 'picture/move_left.png';
                    pause(0.2)
                    app.hand_con.move_xyz(app.nSocket,int32(0),int32(1),int32(move_speed),int32(0));
                    app.still_slide = 1;
                    app.turn_dir = 2;
                    app.move_type = 3;
                    app.movespeed_xyz = move_speed;
                elseif app.wordx_save(1) == 1 && app.wordy_save(1) == 4 && app.still_slide ~= 1 
                    disp("moveright")
                    pause(0.5)
                    reset_pic(app)
                    app.Image_slide_1.ImageSource = 'picture/move_right.png';
                    pause(0.2)
                    app.hand_con.move_xyz(app.nSocket,int32(0),int32(0),int32(move_speed),int32(0))
                    app.still_slide = 1;  
                    app.turn_dir = 1;
                    app.move_type = 4;
                    app.movespeed_xyz = move_speed;
                elseif app.wordx_save(1) == 4 && app.wordy_save(1) == 4 && app.still_slide ~= 2
                    disp("movedown")
                    pause(0.5)
                    reset_pic(app)
                    app.Image_slide_2.ImageSource = 'picture/move_down.png';
                    pause(0.2)
                    app.hand_con.move_xyz(app.nSocket,int32(2),int32(0),int32(move_speed),int32(0)); 
                    app.still_slide = 2;
                    app.turn_dir = 4;
                    app.move_type = 1;
                    app.movespeed_xyz = move_speed;
                elseif app.wordx_save(1) == 4 && app.wordy_save(1) == 1 && app.still_slide ~= 2
                    disp("moveup")
                    pause(0.5)
                    reset_pic(app)
                    app.Image_slide_2.ImageSource = 'picture/move_up.png';
                    pause(0.2)
                    app.hand_con.move_xyz(app.nSocket,int32(2),int32(1),int32(move_speed),int32(0))
                    app.still_slide = 2;
                    app.turn_dir = 3;
                    app.move_type = 2;
                    app.movespeed_xyz = move_speed;
%                 elseif app.still_slide == 1 && app.wordx_save(end) > app.wordx_save(end-1) && app.turn_dir == 2
%                     app.hand_con.stop_move_xyz(app.nSocket)
%                     py.time.sleep(0.2)
%                     disp('turnright')
%                     reset_pic(app)
%                     app.Image_slide_1.ImageSource = 'picture/move_right.png';
%                     app.hand_con.move_xyz(app.nSocket,int32(1),int32(1),int32(move_speed),int32(0))
%                     app.turn_dir = 1;
%                 elseif app.still_slide == 1 && app.wordx_save(end) < app.wordx_save(end-1) && app.turn_dir == 1
%                     app.hand_con.stop_move_xyz(app.nSocket)
%                     py.time.sleep(0.2)
%                     disp('turnleft')
%                     reset_pic(app)
%                     app.Image_slide_1.ImageSource = 'picture/move_left.png';
%                     app.hand_con.move_xyz(app.nSocket,int32(1),int32(0),int32(move_speed),int32(0))
%                     app.turn_dir = 2;
%                 elseif app.still_slide == 2 && app.wordy_save(end) > app.wordy_save(end-1) && app.turn_dir == 4
%                     app.hand_con.stop_move_xyz(app.nSocket)
%                     py.time.sleep(0.2)
%                     disp('turnup')
%                     reset_pic(app)
%                     app.Image_slide_2.ImageSource = 'picture/move_up.png';
%                     app.hand_con.move_xyz(app.nSocket,int32(2),int32(1),int32(move_speed),int32(0))
%                     app.turn_dir = 3;
%                 elseif app.still_slide == 2 && app.wordy_save(end) < app.wordy_save(end-1) && app.turn_dir == 3
%                     app.hand_con.stop_move_xyz(app.nSocket)
%                     py.time.sleep(0.2)
%                     disp('turndown')
%                     reset_pic(app)
%                     app.Image_slide_2.ImageSource = 'picture/move_down.png';
%                     app.hand_con.move_xyz(app.nSocket,int32(2),int32(0),int32(move_speed),int32(0))
%                     app.turn_dir = 4;
                    
                end
                
    
            end


        end
        
        
        function robohand_timer(app,~,~)
            %timer of robot hand
            if ~isempty(app.Ratio1)
                disp([app.Ratio1;app.Ratio2])
                try
                    [app.word_x,app.word_y] = locword(app,app.Ratio1(end),app.Ratio2(end));
                    disp([app.word_x,app.word_y])
                    %contact mode
                    if app.runtime > 100 && ~isempty(app.word_x) && app.slidemode == 0  
                        
                        point_move(app)
%                         if app.lastruntime == app.runtime
%                             pause(0.5)
%                             app.hand_con.stop_move_xyz(app.nSocket)
%                         end
                    end
                    %slide mode
                    if app.slidemode == 1
                        try
                            app.wordx_save = [app.wordx_save,app.word_x(end)];
                            app.wordy_save = [app.wordy_save,app.word_y(end)];
                            disp("word_save:")
                            disp([app.wordx_save;app.wordy_save])    
                            slide_move(app)
                        catch MException
                            disp("slide_wrong")
                        end
                        
                    end
                catch MException
%                     disp(MException);
                    disp("move_wrong")
                end
                
                %record last ratio
                if ~isempty(app.Ratio1)
                    app.last_ratio1 = app.Ratio1(end);       
                end
                if ~isempty(app.Ratio2)
                    app.last_ratio2 = app.Ratio2(end);
                end
                if ~isempty(app.word_x)
                    app.last_wordx = app.word_x;
                end
                if ~isempty(app.word_y)
                    app.last_wordy = app.word_y;
                end

            end

            
%             if ~isempty(app.data_total)
%                 if min(app.data_total)<app.min_thr && length(app.wordx_save)>=2 && app.slidemode == 1
%                     try
%                         disp('begin_slide')
%                         slide_move_norealtime(app);
%                     catch MException
%                         disp(MException);
%                         disp("slide_wrong")
%                     end
%                 end
%             end
        end
        
        function slide_move_norealtime(app) 
            %Non-real-time sliding
            move_speed = 15;

            wordx_save_temp = app.wordx_save;
            wordy_save_temp = app.wordy_save;
            app.wordx_save = [];
            app.wordy_save = [];
            reset_pic(app);
            speed_eff = 0.8;
            
            if sum(wordx_save_temp)>=sum(wordy_save_temp)
                if wordy_save_temp(1)>2
                    move_time = (wordy_save_temp(1)-wordy_save_temp(end))*speed_eff;
                    disp("movedown")
                    app.Image_slide_2.ImageSource = 'picture/move_down.png';
                    app.hand_con.move_xyz(app.nSocket,int32(2),int32(0),int32(move_speed),int32(0))
                    pause(move_time)
                    app.hand_con.stop_move_xyz(app.nSocket)
                else
                    disp("moveup")
                    move_time = (wordy_save_temp(end)-wordy_save_temp(1))*speed_eff;
                    app.Image_slide_2.ImageSource = 'picture/move_up.png';
                    app.hand_con.move_xyz(app.nSocket,int32(2),int32(1),int32(move_speed),int32(0))
                    pause(move_time)
                    app.hand_con.stop_move_xyz(app.nSocket)
                end
            end
            if sum(wordx_save_temp)<sum(wordy_save_temp)
                if wordx_save_temp(1) > 2
                    move_time = (wordx_save_temp(1)-wordx_save_temp(end))*speed_eff;
                    disp("moveleft")
                    app.Image_slide_1.ImageSource = 'picture/move_left.png';
                    app.hand_con.move_xyz(app.nSocket,int32(1),int32(0),int32(move_speed),int32(0))
                    pause(move_time)
                    app.hand_con.stop_move_xyz(app.nSocket)
                else
                    disp("moveright")
                    move_time = (wordx_save_temp(end)-wordx_save_temp(1))*speed_eff;
                    app.Image_slide_1.ImageSource = 'picture/move_right.png';
                    app.hand_con.move_xyz(app.nSocket,int32(1),int32(1),int32(move_speed),int32(0))
                    pause(move_time)
                    app.hand_con.stop_move_xyz(app.nSocket)
                end

            end
            disp('endslide')
            app.slidemode = 0;
            

        end
        
        function reset_pic(app)
            %Drawing interface
            app.Image_logo.ImageSource = 'picture/FIL.png';
            app.Image11.ImageSource = 'picture/clockwise.png';
            app.Image12.ImageSource = 'picture/anticlockwise.png';
            app.Image13.ImageSource = 'picture/grab.png';
            app.Image21.ImageSource = 'picture/z-.png';
            app.Image22.ImageSource = 'picture/y-.png';
            app.Image23.ImageSource = 'picture/x-.png';
            app.Image31.ImageSource = 'picture/z+.png';
            app.Image32.ImageSource = 'picture/y+.png';
            app.Image33.ImageSource = 'picture/x+.png';
            app.Image_slide_1.ImageSource = 'picture/slide_1.png';
            app.Image_slide_2.ImageSource = 'picture/slide_2.png';
            
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %import isoline
            app.Mat1 = importdata('eqlinedata/12_1234.mat');
            app.Mat2 = importdata('eqlinedata/14_1234.mat');
            app.M = length(app.Mat1);
            
            app.xData = -(app.bufflen-1)/app.data_f:1/app.data_f:0;
            %plot row data
            app.h_Ori = animatedline(app.UIAxesrealvolt, MaximumNumPoints=app.bufflen,Color=[197,90,17]./255);
            app.h_Ori2 = animatedline(app.UIAxesrealvolt, MaximumNumPoints=app.bufflen,Color=[112,176,121]./255);
            app.h_Ori3 = animatedline(app.UIAxesrealvolt, MaximumNumPoints=app.bufflen,Color='r');
            app.h_Ori4 = animatedline(app.UIAxesrealvolt, MaximumNumPoints=app.bufflen,Color='b');
            app.UIAxesrealvolt.YLim=[-0.25,0.25];
            %plot coordinate
            app.plot_x = animatedline(app.UIAxes1, MaximumNumPoints=app.bufflen*5,Color='r',LineWidth=2);
            app.plot_y = animatedline(app.UIAxes1, MaximumNumPoints=app.bufflen*5,Color=[112,176,121]./255,LineWidth=2);
            app.plot_z = animatedline(app.UIAxes1, MaximumNumPoints=app.bufflen*5,Color='b',LineWidth=2);

            app.UIAxes1.YLim=[-90,40];
            addpath("D:\Data_Processing\app_robohand\picture")
            reset_pic(app)
            app.Image_xyz.ImageSource = 'picture/plot_xyz.png';
            %connect robot hand
            py.importlib.import_module('robohand_control');
            app.hand_con = py.importlib.import_module('robohand_control');
            py.importlib.reload(app.hand_con);
            app.nSocket = app.hand_con.get_socket();  
            
            
        end

        % Button pushed function: startButton
        function startButtonPushed(app, event)
            app.x = linspace(1,app.M,app.M);
            app.y = linspace(1,app.M,app.M);
            [app.X, app.Y] = meshgrid(app.x,app.y);
            
           
            openAd7606(app);
            
            app.timer_id = timer ('TimerFcn',{@app.adc_timer},'ExecutionMode','fixedDelay','Period',0.1);
            app.timer_id.BusyMode = 'queue';
            app.timer_id_1 = timer ('TimerFcn',{@app.robohand_timer},'ExecutionMode','fixedDelay','Period',0.1);
            app.timer_id_1.BusyMode = 'drop';
            start(app.timer_id);
            start(app.timer_id_1);


        end

        % Button pushed function: stopButton
        function stopButtonPushed(app, event)
            app.last_x=0;
            app.last_y=0;
            app.last_z=0;
            app.now_x=0;
            app.now_y=0;
            app.now_z=0;
            stop(app.timer_id);
            delete(app.timer_id);
            stop(app.timer_id_1);
            delete(app.timer_id_1);
            
            closepy(app);
            
            %             app.hand_con.stop_robohand(app.nSocket)


        end

        % Button pushed function: connectButton
        function connectButtonPushed(app, event)

             
            time_1 = 1;
            while app.nSocket==4
                try
                    app.nSocket = py.robohand_control.get_socket(); 
                catch ME
                    disp(['Error: ' ME.message]);
                end  
                time_1 = time_1+1;
                
                if time_1 > 200
                    disp("连接失败")
                    break;
                end
            end
           
            if app.nSocket>6
                app.hand_con.start_robohand(app.nSocket);
            end
        end

        % Button pushed function: Button
        function startsaveButtonPushed(app, event)
            app.startsave = 1;
            app.endsave = 0;
            app.V1_all = [];
            app.V2_all = [];
            app.V3_all = [];
            app.V4_all = [];
            app.xdata_save = [];
            app.integ_all_save = [];
        end

        % Button pushed function: Button2
        function endsaveButtonPushed(app, event)
            app.endsave = 1;
            data_V1 = app.V1_all;
            data_V2 = app.V2_all;
            data_V3 = app.V3_all;
            data_V4 = app.V4_all;
            data_x  = app.xdata_save;
            data_integ_save = app.integ_all_save;
            save("line_V1.mat","data_V1");
            save("line_V2.mat","data_V2");
            save("line_V3.mat","data_V3");
            save("line_V4.mat","data_V4");
            save("data_x.mat","data_x");
            save("data_integ_save.mat","data_integ_save");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.GridLayout2 = uigridlayout(app.Panel);
            app.GridLayout = uigridlayout(app.GridLayout2);
            app.UIAxes1 = uiaxes(app.GridLayout);
            title(app.UIAxes1, 'Position')
            xlabel(app.UIAxes1, 'Time (s)')
            ylabel(app.UIAxes1, 'displacement(m)')
            zlabel(app.UIAxes1, 'Z')
            app.UIAxes1.YTick = [];
            app.UIAxesrealvolt = uiaxes(app.GridLayout);
            title(app.UIAxesrealvolt, 'Signal')
            xlabel(app.UIAxesrealvolt, 'Time (s)')
            ylabel(app.UIAxesrealvolt, 'Voltage (V)')
            zlabel(app.UIAxesrealvolt, 'Z')
            app.UIAxesrealvolt.YTick = [];
            app.UIAxesline = uiaxes(app.GridLayout);
            zlabel(app.UIAxesline, 'Z')
            app.UIAxesline.XTick = [];
            app.UIAxesline.YTick = [];
            app.startButton = uibutton(app.GridLayout, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Text = 'start';
            app.stopButton = uibutton(app.GridLayout, 'push');
            app.stopButton.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.stopButton.Text = 'stop';
            app.connectButton = uibutton(app.GridLayout, 'push');
            app.connectButton.ButtonPushedFcn = createCallbackFcn(app, @connectButtonPushed, true);
            app.connectButton.Text = 'connect';
            app.Image11 = uiimage(app.GridLayout);
            app.Image12 = uiimage(app.GridLayout);
            app.Image13 = uiimage(app.GridLayout);
            app.Image21 = uiimage(app.GridLayout);
            app.Image22 = uiimage(app.GridLayout);
            app.Image23 = uiimage(app.GridLayout);
            app.Image31 = uiimage(app.GridLayout);
            app.Image32 = uiimage(app.GridLayout);
            app.Image33 = uiimage(app.GridLayout);
            app.Image_slide_1 = uiimage(app.GridLayout);
            app.Image_slide_2 = uiimage(app.GridLayout);
            app.Image_logo = uiimage(app.GridLayout);
            app.Image_xyz = uiimage(app.GridLayout);
            app.Button = uibutton(app.GridLayout, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @startsaveButtonPushed, true);
            app.Button2 = uibutton(app.GridLayout, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @endsaveButtonPushed, true);
            app.Button2.Text = 'Button2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = robohand

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end